classdef OceanUserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        VerificationLogPanel     matlab.ui.container.Panel
        TextArea                 matlab.ui.control.TextArea
        VerifyButton             matlab.ui.control.Button
        OceanPanel               matlab.ui.container.Panel
        MineModeDropDown         matlab.ui.control.DropDown
        MineModeDropDownLabel    matlab.ui.control.Label
        OceanLamp                matlab.ui.control.Lamp
        OceanLampLabel           matlab.ui.control.Label
        SpeedEditField           matlab.ui.control.NumericEditField
        SpeedEditFieldLabel      matlab.ui.control.Label
        DirectionEditField       matlab.ui.control.NumericEditField
        DirectionEditFieldLabel  matlab.ui.control.Label
        ModeDropDown             matlab.ui.control.DropDown
        ModeDropDownLabel        matlab.ui.control.Label
        TurnOnButton             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        OceanEnabled logical = false
        OceanDirection double = 0
        OceanSpeed double = 0
        OceanMode string = "Constant"
        MineMode string = "Non-Tethered"
    
        Controller = []   % placeholder for controller/model connection later                                                                     
    end
    
        methods (Access = private)
        function syncUIState(app)
        app.OceanDirection = app.DirectionEditField.Value;
        app.OceanSpeed = app.SpeedEditField.Value;
        app.OceanMode = string(app.ModeDropDown.Value);
        app.MineMode = string(app.MineModeDropDown.Value);
        end

        function logMessage(app, msg)
        currentLog = app.TextArea.Value;

        if ischar(currentLog)
        currentLog = {currentLog};
        elseif isstring(currentLog)
        currentLog = cellstr(currentLog);
        end

        timestamp = datestr(now, 'HH:MM:SS');
        newEntry = sprintf('%s - %s', timestamp, msg);

        app.TextArea.Value = [currentLog; {newEntry}];
        end

        function updateOceanLamp(app)
        if ~app.OceanEnabled
            app.OceanLamp.Color = [0.5 0.5 0.5];   % gray
        elseif app.OceanSpeed < 0 || app.OceanDirection < 0 || app.OceanDirection > 360
            app.OceanLamp.Color = [1 1 0];         % yellow
        else
            app.OceanLamp.Color = [0 1 0];         % green
        end
       end

       function sendSettingsToController(app)
        % Placeholder for future controller integration
        % Example:
        % if ~isempty(app.Controller)
        % app.Controller.setOceanEnabled(app.OceanEnabled);
        % app.Controller.setOceanMode(app.OceanMode);
        % app.Controller.setMineMode(app.MineMode);
        % app.Controller.setOceanDirection(app.OceanDirection);
        % app.Controller.setOceanSpeed(app.OceanSpeed);
        % end
       end

       function updateControlState(app)
        if app.OceanEnabled
            app.ModeDropDown.Enable = 'on';
            app.DirectionEditField.Enable = 'on';
            app.SpeedEditField.Enable = 'on';
        else
            app.ModeDropDown.Enable = 'off';
            app.DirectionEditField.Enable = 'off';
            app.SpeedEditField.Enable = 'off';
        end

        app.MineModeDropDown.Enable = 'on';
       end 
       end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Set default GUI state
        app.OceanEnabled = false;
        app.OceanDirection = app.DirectionEditField.Value;
        app.OceanSpeed = app.SpeedEditField.Value;
        app.OceanMode = string(app.ModeDropDown.Value);
        app.MineMode = string(app.MineModeDropDown.Value);

        % Set lamp to disabled state initially
        app.OceanLamp.Color = [0.5 0.5 0.5];  % gray

        % Initialize log
        app.TextArea.Value = {'Application started.'; ...
                             'Ocean environment disabled.'};

        % Axes setup
        title(app.UIAxes, 'Mine Simulation Environment')
        xlim(app.UIAxes, [0 1])
        ylim(app.UIAxes, [0 1])
        grid(app.UIAxes, 'on')

        % Initial control state
        app.updateControlState();    
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
        app.syncUIState();

        app.logMessage('Running GUI verification...');

        if app.OceanDirection < 0 || app.OceanDirection > 360
        app.logMessage('FAIL: Direction must be between 0 and 360.');
        return;
        end

        if app.OceanSpeed < 0
        app.logMessage('FAIL: Speed must be non-negative.');
        return;
        end

        app.logMessage(['PASS: Ocean enabled = ' char(string(app.OceanEnabled))]);
        app.logMessage(['PASS: Ocean mode = ' char(app.OceanMode)]);
        app.logMessage(['PASS: Mine mode = ' char(app.MineMode)]);
        app.logMessage(sprintf('PASS: Direction = %.2f deg', app.OceanDirection));
        app.logMessage(sprintf('PASS: Speed = %.2f', app.OceanSpeed));
        app.logMessage('GUI verification complete.');    
        end

        % Button pushed function: TurnOnButton
        function TurnOnButtonPushed(app, event)
        app.OceanEnabled = ~app.OceanEnabled;

        if app.OceanEnabled
        app.TurnOnButton.Text = 'Turn Off';
        app.logMessage('Ocean environment enabled.');
        else
        app.TurnOnButton.Text = 'Turn On';
        app.logMessage('Ocean environment disabled.');
        end

        app.updateOceanLamp();
        app.updateControlState();
        app.sendSettingsToController();    
        end

        % Value changed function: DirectionEditField
        function DirectionEditFieldValueChanged(app, event)
        app.syncUIState();
        app.updateOceanLamp();
        app.sendSettingsToController();
        app.logMessage(sprintf('Direction set to %.2f degrees.', app.OceanDirection));      
        end

        % Value changed function: SpeedEditField
        function SpeedEditFieldValueChanged(app, event)
        app.syncUIState();
        app.updateOceanLamp();
        app.sendSettingsToController();
        app.logMessage(sprintf('Speed set to %.2f.', app.OceanSpeed));      
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
        app.syncUIState();
        app.logMessage(['Ocean mode changed to: ' char(app.OceanMode)]);
        app.sendSettingsToController();
        end

        % Value changed function: MineModeDropDown
        function MineModeDropDownValueChanged(app, event)
        app.syncUIState();
        app.logMessage(['Mine mode changed to: ' char(app.MineMode)]);
        app.sendSettingsToController();        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mine Simulation Environment ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OceanPanel = uipanel(app.UIFigure);
            app.OceanPanel.Title = 'Ocean Panel';
            app.TurnOnButton = uibutton(app.OceanPanel, 'push');
            app.TurnOnButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOnButtonPushed, true);
            app.TurnOnButton.Text = 'Turn On';
            app.ModeDropDownLabel = uilabel(app.OceanPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.OceanPanel);
            app.ModeDropDown.Items = {'Constant', 'Gradient ', 'Stochastic '};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Constant';
            app.DirectionEditFieldLabel = uilabel(app.OceanPanel);
            app.DirectionEditFieldLabel.Text = 'Direction ';
            app.DirectionEditField = uieditfield(app.OceanPanel, 'numeric');
            app.DirectionEditField.ValueChangedFcn = createCallbackFcn(app, @DirectionEditFieldValueChanged, true);
            app.SpeedEditFieldLabel = uilabel(app.OceanPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.OceanPanel, 'numeric');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedEditFieldValueChanged, true);
            app.OceanLampLabel = uilabel(app.OceanPanel);
            app.OceanLampLabel.Text = 'Ocean Lamp';
            app.OceanLamp = uilamp(app.OceanPanel);
            app.MineModeDropDownLabel = uilabel(app.OceanPanel);
            app.MineModeDropDownLabel.Text = 'Mine Mode';
            app.MineModeDropDown = uidropdown(app.OceanPanel);
            app.MineModeDropDown.Items = {'Non-Tethered ', 'Tethered'};
            app.MineModeDropDown.ValueChangedFcn = createCallbackFcn(app, @MineModeDropDownValueChanged, true);
            app.MineModeDropDown.Value = 'Non-Tethered ';
            app.VerificationLogPanel = uipanel(app.UIFigure);
            app.VerificationLogPanel.Title = 'Verification / Log';
            app.VerifyButton = uibutton(app.VerificationLogPanel, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.TextArea = uitextarea(app.VerificationLogPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OceanUserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end