classdef ParticleEmitterSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        Panel                     matlab.ui.container.Panel
        ParticleCountSlider       matlab.ui.control.Slider
        ParticleCountSliderLabel  matlab.ui.control.Label
        ParticleSpeedSlider       matlab.ui.control.Slider
        ParticleSpeedSliderLabel  matlab.ui.control.Label
        TriggerDemoButton         matlab.ui.control.Button
        VerifyButton              matlab.ui.control.Button
        LogTextArea               matlab.ui.control.TextArea
        LogTextAreaLabel          matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        emitter % Store the SEParticleEmitter object
        env     % Store the SEEnvironment object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Link the Logger
            logger = Logger.getInstance();
            logger.setTextHandle(app.LogTextArea);
            
            % Setup 3D Axes
            view(app.UIAxes, 3);
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');
            
            % --- EXPANDED TABLE LIMITS ---
            app.UIAxes.XLim = [0 50]; 
            app.UIAxes.YLim = [0 50]; 
            app.UIAxes.ZLim = [-2 10]; % Increased Z height to see the 3D spray
            
            % Initialize Environment
            app.env = SEEnvironment();
            force = app.env.globalForce;
            
            % --- UPDATED QUIVER POSITION ---
            % Moved the arrow to the new corner (45, 45) and scaled it by 5
            quiver3(app.UIAxes, 45, 45, 0, force(1)*5, force(2)*5, force(3)*5, ...
                'Color', '#0072BD', 'LineWidth', 2, 'MaxHeadSize', 2);
            text(app.UIAxes, 45, 45, 1, 'Env Force', 'Color', '#0072BD', 'FontWeight', 'bold');
            
            % FIXED LOGGER COMMAND:
            logger.log('STATUS', '3D Emitter Tool Ready (Expanded 50x50 Grid).');
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            % Create a temp emitter the test
            testEmitter = SEParticleEmitter(app.UIAxes, 50);
            testEmitter.environment = app.env;
            
            % Execute the verification contract (results appear in LogTextArea)
            testEmitter.verify();
            
            % Cleanup
            delete(testEmitter);            
        end

        % Button pushed function: TriggerDemoButton
        function TriggerDemoButtonPushed(app, event)
    % Get the logger instance
    logger = Logger.getInstance();
    
    if ~isempty(app.emitter); delete(app.emitter); end
    
    % 1. Read values and LOG them
    numParts = round(double(app.ParticleCountSlider.Value(1)));
    speedMult = app.ParticleSpeedSlider.Value(1) * 20; 
    
    logger.log('INFO', sprintf('Starting demo: %d particles, Speed x%.1f', numParts, speedMult));
    
    % 2. Instantiate and Trigger
    app.emitter = SEParticleEmitter(app.UIAxes, numParts);
    app.emitter.environment = app.env;
    app.emitter.trigger([25, 25, 0], [speedMult, speedMult, 0]);
    
    % 3. Animation Loop
    dt = 0.1;
    while isvalid(app.emitter) && app.emitter.is_active
        app.emitter.update(dt);
        drawnow limitrate;
        pause(0.05); 
    end
    
    % 4. LOG THE COMPLETION
    logger.log('STATUS', 'Simulation complete. All particles expired.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '3D Particle Detonation Demo')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LogTextAreaLabel = uilabel(app.Panel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.Panel);
            app.VerifyButton = uibutton(app.Panel, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.TriggerDemoButton = uibutton(app.Panel, 'push');
            app.TriggerDemoButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerDemoButtonPushed, true);
            app.TriggerDemoButton.Text = 'Trigger Demo';
            app.ParticleSpeedSliderLabel = uilabel(app.Panel);
            app.ParticleSpeedSliderLabel.Text = 'Particle Speed';
            app.ParticleSpeedSlider = uislider(app.Panel);
            app.ParticleSpeedSlider.Limits = [0 20];
            app.ParticleCountSliderLabel = uilabel(app.Panel);
            app.ParticleCountSliderLabel.Text = 'Particle Count';
            app.ParticleCountSlider = uislider(app.Panel);
            app.ParticleCountSlider.Limits = [10 200];
            app.ParticleCountSlider.Step = 1;
            app.ParticleCountSlider.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParticleEmitterSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end