classdef mobilemineSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayoutWest                 matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
        SideViewAxes                   matlab.ui.control.UIAxes
        GridLayoutEast                 matlab.ui.container.GridLayout
        ConsoleTextArea                matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        OceanLamp                      matlab.ui.control.Lamp
        OceanLabel                     matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        ViewDropDown                   matlab.ui.control.DropDown
        ViewDropDownLabel              matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        AnimateCheckBox                matlab.ui.control.CheckBox
        StartButton                    matlab.ui.control.Button
        TimeLimitsEditField            matlab.ui.control.NumericEditField
        TimeLimitsEditFieldLabel       matlab.ui.control.Label
        NumRunsEdit                    matlab.ui.control.NumericEditField
        RunsLabel                      matlab.ui.control.Label
        FleetTab                       matlab.ui.container.Tab
        FleetBehaviorMenu              matlab.ui.control.DropDown
        BehaviorDropDown_2Label        matlab.ui.control.Label
        NumShipsEdit                   matlab.ui.control.NumericEditField
        ShipsLabel                     matlab.ui.control.Label
        MinefieldTab                   matlab.ui.container.Tab
        ImportButton                   matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        MineTypeDropDown               matlab.ui.control.DropDown
        MineTypeDropDownLabel          matlab.ui.control.Label
        DetectionRangemEditField       matlab.ui.control.NumericEditField
        DetectionRangemEditFieldLabel  matlab.ui.control.Label
        LayoutDropDown                 matlab.ui.control.DropDown
        LayoutDropDownLabel            matlab.ui.control.Label
        DamageRangemEditField          matlab.ui.control.NumericEditField
        DamageRangemEditFieldLabel     matlab.ui.control.Label
        MineCountEditField             matlab.ui.control.NumericEditField
        MineCountEditFieldLabel        matlab.ui.control.Label
        OceanTab                       matlab.ui.container.Tab
        ShowCompassCheckBox            matlab.ui.control.CheckBox
        CurrentSpeedEditField          matlab.ui.control.NumericEditField
        CurrentSpeedEditFieldLabel     matlab.ui.control.Label
        CurentHeadingEditField         matlab.ui.control.NumericEditField
        CurentHeadingEditFieldLabel    matlab.ui.control.Label
        EnviromentModeDropDown         matlab.ui.control.DropDown
        EnviromentModeDropDownLabel    matlab.ui.control.Label
        DegreesLabel                   matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        HTML                           matlab.ui.control.HTML
        ContextMenu                    matlab.ui.container.ContextMenu
        DebugMenu                      matlab.ui.container.Menu
        UnlockMenu                     matlab.ui.container.Menu
        RRMenu                         matlab.ui.container.Menu
    end

    
    % Team Sea Shield: - 
    % Added renderer3D, boundary_box, minefield_box, and localMaxSteps as private properties.
    %------------------------------------------------%
    % Team Sea Shield:
    % Added resetDisplays() to reset the overhead map, reset the side-view renderer, and refresh both views.
    % Added applyMineRanges() so damage and detection range fields update mine settings.
    % Updated refreshDisplay() so it refreshes both the overhead map and the side view.
    % Updated simUpdatedCb() so the side view updates continuously during simulation.    
    %------------------------------------------------%
    properties (Access = private)
        renderer3D
        boundary_box (1,4) double = [0, 0.1, 6, 8.9]
        minefield_box (1,4) double = [0.5, 1, 5.0, 7.0]
        oceanVectorGraphic = [];        
    end
    
    properties (SetAccess = public)
        simEngine % SESimulatorEngine
    end
    
    methods (Access = public)        
        function setHtml(app, htmlContent)
            if nargin < 2 || isempty(htmlContent)
                htmlContent = '';
            end
            htmlStr = sprintf('<html><body>%s</body></html>', htmlContent);
            app.HTML.HTMLSource = htmlStr;
        end

        function numMines = getNumMines(app)
            numMines = app.MineCountEditField.Value;
        end

        function numShips = getNumShips(app)
            numShips = app.NumShipsEdit.Value;
        end

        function setNumShips(app, num_ships)            
            app.simEngine.setNumShips(num_ships);
            app.refreshDisplay();
        end

        function setNumMines(app, num_mines)
            app.simEngine.setNumMines(num_mines);
            app.refreshDisplay();
        end

        function didSet = setMinefieldLayout(app, layout)
            didSet = app.simEngine.setMinefieldLayout(layout);
            app.refreshDisplay();
        end

        function setFleetBehavior(app, behavior)
            app.simEngine.setFleetBehavior(behavior);
            app.refreshDisplay();
        end

        function refreshDisplay(app)
            % Updated to refresh both 2d and side view display.
            % Sideview must be updated every time the sim display refreshes.
            if isempty(app.simEngine)
                return;
            end

            app.simEngine.refreshDisplay();

            if ~isempty(app.renderer3D) && isvalid(app.renderer3D)
                app.renderer3D.update(app.simEngine);
            end
        end

        function disableWidgets(app)
            app.setEnableWidgets(false);
            app.AnimateCheckBox.Enable="on";
            app.StopButton.Enable = "on";
            % app.ResetButton.Enable
            % app.StartButton.Enable = "on";
        end

        function enableWidgets(app)
            app.setEnableWidgets(true);
        end

        function setTitle(app, titleStr, varargin)
            titleStr = sprintf(titleStr, varargin{:});
            app.UIFigure.Name = titleStr;
        end

        function setEnableWidgets(app, enabled)
            tabs = app.TabGroup.Children;
            if enabled
                enabledTag = 'on';
            else
                enabledTag = 'off';
            end
            for t=1:numel(tabs)
                tab = tabs(t);
                childrenH = tab.Children;
                for c=1:numel(childrenH)
                    h = childrenH(c);
                    if isprop(h,'enable')
                        set(h,'enable',enabledTag);
                    end
                end
            end
        end

        function updateStatistics(app)
            % TODO (Joe E) calculate current measures of effectiveness
            % Here is an example - you will need to make sure that
            % simEngine team has these methods - they will need to create
            % them if they don't
            stats = app.simEngine.getStatistics();

            %             num_mines = app.simEngine.numMines;
            %             num_ships = app.simEngine.numShips;
            %             num_mines_remaining = app.simEngine.getNumUnexplodedMines();
            %             num_ships_remaining = app.simEngine.getNumUnsunkShips();
            %
            %             moe_1 = nan;
            %             if num_ships > 0
            %                 moe_1 = 1 - num_ships_remaining / num_ships;
            %             end            
            % statsStr = sprintf('<b>MOE 1</b>%f<br>', moe_1);


            % Generate the HTML string
            % htmlStr = sprintf(['<html>'...
            %     '<head><style>'...
            %     'table {font-family: Arial, sans-serif; border-collapse: collapse; width: 100%%;}'...
            %     'th, td {border: 1px solid #dddddd; text-align: left; padding: 8px;}'...
            %     'th {background-color: #f2f2f2;}'...
            %     '</style></head>'...
            %     '<body>'...
            %     '<h2>Statistics</h2>'...
            %     '<table>'...
            %     '<tr><th>Statistic</th><th>Value</th></tr>'...
            %     '<tr><td>Ships Remaining</td><td>%d</td></tr>'...
            %     '<tr><td>Ships Sunk</td><td>%d</td></tr>'...
            %     '<tr><td>Proportion of Ships Remaining</td><td>%.2f</td></tr>'...
            %     '<tr><td>Mines Remaining</td><td>%d</td></tr>'...                
            %     '<tr><td>Mines Detonated</td><td>%d</td></tr>'...
            %     '<tr><td>Proportion of Mines Remaining</td><td>%.2f</td></tr>'...
            %     '</table>'...
            %     '</body>'...
            %     '</html>'], stats.shipsRemaining, stats.shipsSunk, stats.proportionShipsRemaining, stats.minesRemaining, stats.minesDetonated, stats.proportionMinesRemaining);
            % 
            % app.setHtml(htmlStr);
            % 

            % Generate the HTML string
            htmlString = sprintf(['<html>'...
                '<head><style>'...
                'table {font-size: 12px; font-family: Arial, sans-serif; border-collapse: collapse; width: 100%%;}'...
                'th, td {border: 1px solid #dddddd; text-align: right; padding: 8px;}'...
                'th {background-color: #f2f2f2;}'...
                'h2 {font-family: Arial, sans-serif;}'...
                '</style></head>'...
                '<body>'...
                '<h2></h2>'...
                '<table>'...
                '<tr><th></th><th>Ships</th><th>Mines</th></tr>'...
                '<tr><td><b>Remaining</b></td><td>%d</td><td>%d</td></tr>'...
                '<tr><td><b>Destroyed</b></td><td>%d</td><td>%d</td></tr>'...
                '<tr><td><b>Percent Remaining</b></td><td>%.1f%%</td><td>%.1f%%</td></tr>'...
                '</table>'...
                '</body>'...
                '</html>'], ...
                stats.shipsRemaining, stats.minesRemaining, ...
                stats.shipsSunk, stats.minesDetonated, ...
                stats.proportionShipsRemaining*100, stats.proportionMinesRemaining*100);
            app.setHtml(htmlString);
        end
        
        function setOceanEnabled(app, enabledState)
            app.simEngine.oceanEnv.setEnabled(enabledState);
            app.ShowCompassCheckBox.Value = enabledState;
            if enabledState
                app.OceanLamp.Color = [0 1 0]; % Green (Active)                
                app.simEngine.logStatus('Ocean environment / Compass enabled.');
            else
                app.OceanLamp.Color = [0.5 0.5 0.5]; % Gray (Off)
                app.simEngine.logStatus('Ocean environment / Compass disabled.');
            end
            
            app.updateOceanVector();

        end
    end
    
    methods (Access = private)
        function simUpdatedCb(app, simEngineH, evt)
            % Statistics and both displays update continuously while running.
            app.updateStatistics();
            app.refreshDisplay();
            drawnow limitrate;
        end

        function applyMineRanges(app)
            if isempty(app.simEngine) || isempty(app.simEngine.minefield)
                return;
            end

            mines = app.simEngine.minefield.mines;
            damageRange = app.DamageRangemEditField.Value;
            detectRange = app.DetectionRangemEditField.Value;

            for k = 1:numel(mines)
                try
                    mines(k).damageRange = damageRange;
                    mines(k).detectRange = detectRange;
                catch
                end
            end
        end

        function resetSimulation(app)
            
            app.applyMineRanges();
            app.resetDisplays();
        end

        function resetDisplays(app)
            % cla(app.UIAxes);
            % hold(app.UIAxes, 'on');
            % axis(app.UIAxes, 'equal');
            % xlim(app.UIAxes, [0 6]);
            % ylim(app.UIAxes, [0 9]);
            % set(app.UIAxes, ...
            %     'Color', [0.6745 0.9686 0.9882], ...
            %     'Box', 'on', ...
            %     'XGrid', 'on', ...
            %     'YGrid', 'on');

            title(app.UIAxes, 'Overhead Map Perspective');

            app.simEngine.refreshDisplay();

            if ~isempty(app.renderer3D) && isvalid(app.renderer3D)
                app.renderer3D.reset();
                app.renderer3D.update(app.simEngine);
            end

            app.syncViewDropDown();
            app.updateStatistics();
        end

        function syncViewDropDown(app)

            if isempty(app.renderer3D) || ~isvalid(app.renderer3D)
                app.ViewDropDown.Items = {'Unavailable'};
                app.ViewDropDown.Value = 'Unavailable';
                return;
            end

            app.ViewDropDown.Items = app.renderer3D.getViewNames();
            app.ViewDropDown.Value = app.renderer3D.getViewName();
        end

        function isIt = isOceanEnabled(app)
            isIt = app.simEngine.oceanEnv.isEnabled;
        end

        function updateOceanVector(app)
            % Clear existing graphic
            app.deleteOceanVectorGraphic();

            env = app.simEngine.oceanEnv;
            if isempty(env) || ~isvalid(env)
                return;
            end

            x0 = app.boundary_box(1);
            y0 = app.boundary_box(2);
            w  = app.boundary_box(3);
            h  = app.boundary_box(4);
            margin = 1.0;

            anchorX = x0 + w - margin;
            anchorY = y0 + h - margin;

            spec = env.getCompassSpec(anchorX, anchorY);
            
            if ~spec.isVisible
                return;
            end

            hold(app.UIAxes, 'on');
            app.oceanVectorGraphic = hggroup(app.UIAxes);

            % Compass ring
            plot(app.UIAxes, spec.ringX, spec.ringY, ...
                'Color', [0.7 0.7 0.7], ...
                'LineWidth', 1.5, ...
                'LineStyle', '--', ...
                'Parent', app.oceanVectorGraphic);

            % Labels
            for k = 1:size(spec.labels, 1)
                text(app.UIAxes, spec.labels{k,2}, spec.labels{k,3}, spec.labels{k,1}, ...
                    'HorizontalAlignment', 'center', ...
                    'FontSize', 9, ...
                    'FontWeight', 'bold', ...
                    'Color', [0.3 0.3 0.3], ...
                    'Parent', app.oceanVectorGraphic);
            end

            % Direction arrow
            quiver(app.UIAxes, spec.anchorX, spec.anchorY, spec.U, spec.V, 0, ...
                'Color', [0 0.4470 0.7410], ...
                'LineWidth', 3, ...
                'MaxHeadSize', 1.5, ...
                'Parent', app.oceanVectorGraphic);

            hold(app.UIAxes, 'off');
        end

        function deleteOceanVectorGraphic(app)
            if ~isempty(app.oceanVectorGraphic) && isvalid(app.oceanVectorGraphic)
                delete(app.oceanVectorGraphic);
            end
            app.oceanVectorGraphic = matlab.graphics.GraphicsPlaceholder.empty;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            logger = Logger.getInstance();
            logger.setTextHandle(app.ConsoleTextArea);


            app.ResetButton.Visible = 'off';
            
            app.simEngine = SESimulatorEngine();

            addlistener(app.simEngine, 'SimUpdated', @app.simUpdatedCb);

            % Keep your existing listeners only if those event handlers already exist
            % in your class. Otherwise leave them out.
            % addlistener(app.simEngine, 'SimCompleted', @app.simCompletedCb);
            % addlistener(app.simEngine, 'MonteCarloFinished', @app.runCompletedCb);
            app.setTitle('NPS Mine Simulator');


            % Make sure the time is synced
            app.simEngine.setTimeLimit(app.TimeLimitsEditField.Value);

            % Sync the ocean environment
            value = app.EnviromentModeDropDown.Value;
            app.simEngine.oceanEnv.setMode(value);       
            value = app.CurentHeadingEditField.Value;
            app.simEngine.oceanEnv.setHeading(value);
            value = app.CurrentSpeedEditField.Value;
            app.simEngine.oceanEnv.setSpeed(value);
            app.setOceanEnabled(app.isOceanEnabled);

            
            % this gets turned off occassionally, perhaps when working with
            % the Design View or when switching between MATLAB versions.
            app.UIAxes.Visible = 'on'; 
            % INITIALIZE THE UI
            app.NumRunsEdit.Value = 1;
            app.NumRunsEdit.Enable = "off";

            % Make sure we have valid dropdown values
            fleet_behaviors = app.simEngine.getValidFleetBehaviors();
            minefield_layouts = app.simEngine.getValidMinefieldLayouts();
            
            app.FleetBehaviorMenu.Items = fleet_behaviors;
            app.LayoutDropDown.Items = minefield_layouts; 

            mineTypes = SEMinefield.MINE_TYPES;
            app.MineTypeDropDown.Items = mineTypes;
            app.MineTypeDropDown.Value = 'static';

            % Get the boundary box items and then pass these along
            % to the actual call when you are ready. 
            app.simEngine.setBoundaryBox(app.boundary_box);            
            app.simEngine.setMinefieldBox(app.minefield_box);
            app.simEngine.setAxesHandle(app.UIAxes);
            app.simEngine.setTacticalAxesHandle(app.SideViewAxes);

            % This part is not complete in the simulator engine - commented out
            % app.simEngine.setdamageRange(500);
            % app.simEngine.setdetectionRange(3000);

            % Sync properties of the U/I with the simulator engine            
            app.setFleetBehavior(app.FleetBehaviorMenu.Value);
            app.setMinefieldLayout(app.LayoutDropDown.Value);
            app.simEngine.setMineType(app.MineTypeDropDown.Value);            
            app.setNumMines(app.getNumMines());
            app.setNumShips(app.getNumShips());

            % SideView initialization and preset 
            app.renderer3D = SESideViewTacticalStatus(app.SideViewAxes, app.boundary_box, app.minefield_box);            
            app.syncViewDropDown();

            app.refreshDisplay();

            app.setHtml('Ready');

            app.simEngine.logStatus('startupFcn complete');
        end

        % Value changed function: NumRunsEdit
        function NumRunsEditCb(app, event)
            value = app.NumRunsEdit.Value;
            app.simEngine.setNumRuns(value);
        end

        % Value changed function: TimeLimitsEditField
        function TimeLimitsEditFieldCb(app, event)
            value = app.TimeLimitsEditField.Value;
            app.simEngine.setTimeLimit(value);
        end

        % Button pushed function: StartButton
        function StartButtonPushedCb(app, event)
            %PENDING TO ADD THIS FUNCTION TO RUN THE SIMULATION
            app.disableWidgets();
            app.setHtml('Running');
            drawnow();
            try
                if isempty(app.simEngine) || ~isvalid(app.simEngine)
                    error('simEngine was not initialized correctly.');
                end

                app.simEngine.run();
                app.updateStatistics();

            catch ME
                uialert(app.UIFigure, ME.message, 'Start Error');
            end
            app.enableWidgets();

        end

        % Value changed function: NumShipsEdit
        function NumShipsEditValueChanged(app, event)
            value = app.NumShipsEdit.Value;
            app.setNumShips(value);
            % fprintf(1,'Number of ships changed to: %d\n', value);
        end

        % Value changed function: FleetBehaviorMenu
        function FleetBehaviorMenuValueChanged(app, event)
            value = app.FleetBehaviorMenu.Value;
            app.setFleetBehavior(value);
            % fprintf(1,'Fleet behavior is now: %s\n', value);
        end

        % Value changed function: MineCountEditField
        function MineCountEditFieldValueChanged(app, event)
            value = app.MineCountEditField.Value;
            app.setNumMines(value);
        end

        % Value changed function: LayoutDropDown
        function LayoutDropDownCb(app, event)
            value = app.LayoutDropDown.Value;
            app.setMinefieldLayout(value);            
        end

        % Menu selected function: UnlockMenu
        function UnlockMenuSelected(app, event)
            app.enableWidgets();
        end

        % Menu selected function: RRMenu
        function RRMenuSelected(app, event)
            web('https://youtu.be/dQw4w9WgXcQ?si=P4Xfbnwc87U4JXh8');
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            data = struct( ...
                'MineCount', app.MineCountEditField.Value, ...
                'Layout', app.LayoutDropDown.Value, ...
                'DamageRange_m', app.DamageRangemEditField.Value, ...
                'DetectionRange_m', app.DetectionRangemEditField.Value, ...
                'MineType', app.MineTypeDropDown.Value ...
                );
            [file, path] = uiputfile('*.json', 'Save Minefield Configuration');
            if file
                jsonText = jsonencode(data);
                fid = fopen(fullfile(path, file), 'w');
                if fid ~= -1
                    fwrite(fid, jsonText, 'char');
                    fclose(fid);
                end
            end
            figure(app.UIFigure); % Brings the UIFigure to focus
            app.UIFigure.Visible = 'on'; % Ensure it's visible
            drawnow; % Force update of graphics
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file, path] = uigetfile('*.json', 'Select Minefield Configuration');
            if isequal(file, 0)
                return; % User canceled
            end

            fullFilePath = fullfile(path, file);

            % Read and decode JSON
            fid = fopen(fullFilePath, 'r');
            if fid == -1
                uialert(app.UIFigure, 'Unable to open the file.', 'File Error');
                return;
            end

            raw = fread(fid, inf, 'char=>char')';
            fclose(fid);

            try
                data = jsondecode(raw);

                % Assign values to UI components
                if isfield(data, 'MineCount')
                    app.MineCountEditField.Value = data.MineCount;
                end
                if isfield(data, 'Layout')
                    app.LayoutDropDown.Value = data.Layout;
                end
                if isfield(data, 'DamageRange_m')
                    app.DamageRangemEditField.Value = data.DamageRange_m;
                end
                if isfield(data, 'DetectionRange_m')
                    app.DetectionRangemEditField.Value = data.DetectionRange_m;
                end
                if isfield(data, 'MineType')
                    app.MineTypeDropDown.Value = data.MineType;
                end

            catch ME
                uialert(app.UIFigure, ['Error reading JSON: ' ME.message], 'Import Error');
            end
            value = app.MineCountEditField.Value;
            app.setNumMines(value);
            value = app.LayoutDropDown.Value;
            app.setMinefieldLayout(value);
            app.refreshDisplay();
            figure(app.UIFigure); % Brings the UIFigure to focus
            app.UIFigure.Visible = 'on'; % Ensure it's visible
            drawnow; % Force update of graphics

        end

        % Drop down opening function: LayoutDropDown
        function LayoutDropDownOpening(app, event)
            
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            if app.simEngine.debugMode
                app.DebugMenu.Checked = 'on';
            else
                app.DebugMenu.Checked = 'off';
            end
        end

        % Menu selected function: DebugMenu
        function DebugMenuSelected(app, event)
            % toggle debug mode
            app.simEngine.setDebugMode(~app.simEngine.debugMode);

            % update check mark
            if app.simEngine.debugMode
                % app.DebugMenu.Checked = 'on';
                app.ConsoleTextArea.Visible = 'on';
            else
                % app.DebugMenu.Checked = 'off';
                app.ConsoleTextArea.Visible = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if ~isempty(app.simEngine)
                    app.simEngine.stop();
                    delete(app.simEngine);
                end
            catch
            end
            delete(app)            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.simEngine.stop();
        end

        % Value changed function: MineTypeDropDown
        function MineTypeDropDownValueChanged(app, event)
            value = app.MineTypeDropDown.Value;
            app.simEngine.setMineType(value);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetSimulation();
            app.setHtml('Reset complete');
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            if isempty(app.renderer3D) || ~isvalid(app.renderer3D)
                return;
            end

            app.renderer3D.setViewByName(app.ViewDropDown.Value);

            if ~isempty(app.simEngine) && isvalid(app.simEngine)
                app.renderer3D.update(app.simEngine);
            end
            
        end

        % Value changed function: ShowCompassCheckBox
        function ShowCompassCheckBoxValueChanged(app, event)
            app.setOceanEnabled(app.ShowCompassCheckBox.Value);            
        end

        % Value changed function: EnviromentModeDropDown
        function EnviromentModeDropDownValueChanged(app, event)
            value = app.EnviromentModeDropDown.Value;
            app.simEngine.oceanEnv.setMode(value);
        end

        % Value changed function: CurentHeadingEditField
        function CurentHeadingEditFieldValueChanged(app, event)
            value = app.CurentHeadingEditField.Value;
            app.simEngine.oceanEnv.setHeading(value);
            app.updateOceanVector();
        end

        % Value changed function: CurrentSpeedEditField
        function CurrentSpeedEditFieldValueChanged(app, event)
            value = app.CurrentSpeedEditField.Value;
            app.simEngine.oceanEnv.setSpeed(value);
            % app.updateOceanVector();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayoutEast = uigridlayout(app.GridLayout);
            app.HTML = uihtml(app.GridLayoutEast);
            app.TabGroup = uitabgroup(app.GridLayoutEast);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.RunsLabel = uilabel(app.SimulationTab);
            app.RunsLabel.Text = 'Number of Runs';
            app.NumRunsEdit = uieditfield(app.SimulationTab, 'numeric');
            app.NumRunsEdit.ValueChangedFcn = createCallbackFcn(app, @NumRunsEditCb, true);
            app.NumRunsEdit.Value = 1;
            app.TimeLimitsEditFieldLabel = uilabel(app.SimulationTab);
            app.TimeLimitsEditFieldLabel.Text = 'Time Limit (s)';
            app.TimeLimitsEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TimeLimitsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLimitsEditFieldCb, true);
            app.TimeLimitsEditField.Value = 30;
            app.StartButton = uibutton(app.SimulationTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushedCb, true);
            app.StartButton.Text = 'Start';
            app.AnimateCheckBox = uicheckbox(app.SimulationTab);
            app.AnimateCheckBox.Text = 'Animate';
            app.AnimateCheckBox.Value = true;
            app.StopButton = uibutton(app.SimulationTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ViewDropDownLabel = uilabel(app.SimulationTab);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.SimulationTab);
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ResetButton = uibutton(app.SimulationTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OceanLabel = uilabel(app.SimulationTab);
            app.OceanLabel.Text = 'Ocean ';
            app.OceanLamp = uilamp(app.SimulationTab);
            app.FleetTab = uitab(app.TabGroup);
            app.FleetTab.Title = 'Fleet';
            app.ShipsLabel = uilabel(app.FleetTab);
            app.ShipsLabel.Text = 'Ships';
            app.NumShipsEdit = uieditfield(app.FleetTab, 'numeric');
            app.NumShipsEdit.ValueChangedFcn = createCallbackFcn(app, @NumShipsEditValueChanged, true);
            app.NumShipsEdit.Value = 10;
            app.BehaviorDropDown_2Label = uilabel(app.FleetTab);
            app.BehaviorDropDown_2Label.Text = 'Behavior';
            app.FleetBehaviorMenu = uidropdown(app.FleetTab);
            app.FleetBehaviorMenu.Items = {'Kamikaze', 'Random', 'Channelized'};
            app.FleetBehaviorMenu.ValueChangedFcn = createCallbackFcn(app, @FleetBehaviorMenuValueChanged, true);
            app.FleetBehaviorMenu.Value = 'Kamikaze';
            app.MinefieldTab = uitab(app.TabGroup);
            app.MinefieldTab.Title = 'Minefield';
            app.MineCountEditFieldLabel = uilabel(app.MinefieldTab);
            app.MineCountEditFieldLabel.Text = 'Mine Count';
            app.MineCountEditField = uieditfield(app.MinefieldTab, 'numeric');
            app.MineCountEditField.Limits = [0 10000];
            app.MineCountEditField.ValueChangedFcn = createCallbackFcn(app, @MineCountEditFieldValueChanged, true);
            app.MineCountEditField.Value = 31;
            app.DamageRangemEditFieldLabel = uilabel(app.MinefieldTab);
            app.DamageRangemEditFieldLabel.Text = 'Damage Range (m)';
            app.DamageRangemEditField = uieditfield(app.MinefieldTab, 'numeric');
            app.DamageRangemEditField.Value = 500;
            app.LayoutDropDownLabel = uilabel(app.MinefieldTab);
            app.LayoutDropDownLabel.Text = 'Layout';
            app.LayoutDropDown = uidropdown(app.MinefieldTab);
            app.LayoutDropDown.Items = {'Random', 'Smart', 'Dumb'};
            app.LayoutDropDown.DropDownOpeningFcn = createCallbackFcn(app, @LayoutDropDownOpening, true);
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @LayoutDropDownCb, true);
            app.LayoutDropDown.Value = 'Smart';
            app.DetectionRangemEditFieldLabel = uilabel(app.MinefieldTab);
            app.DetectionRangemEditFieldLabel.Text = 'Detection Range (m)';
            app.DetectionRangemEditField = uieditfield(app.MinefieldTab, 'numeric');
            app.DetectionRangemEditField.Value = 500;
            app.MineTypeDropDownLabel = uilabel(app.MinefieldTab);
            app.MineTypeDropDownLabel.Text = 'Mine Type';
            app.MineTypeDropDown = uidropdown(app.MinefieldTab);
            app.MineTypeDropDown.Items = {'Mobile', 'Static', 'Tethered', 'Detect_Release'};
            app.MineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MineTypeDropDownValueChanged, true);
            app.MineTypeDropDown.Value = 'Mobile';
            app.ExportButton = uibutton(app.MinefieldTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ImportButton = uibutton(app.MinefieldTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.OceanTab = uitab(app.TabGroup);
            app.OceanTab.Title = 'Ocean';
            app.msLabel = uilabel(app.OceanTab);
            app.msLabel.Text = 'm/s';
            app.DegreesLabel = uilabel(app.OceanTab);
            app.DegreesLabel.Text = 'Degrees';
            app.EnviromentModeDropDownLabel = uilabel(app.OceanTab);
            app.EnviromentModeDropDownLabel.Text = 'Enviroment  Mode';
            app.EnviromentModeDropDown = uidropdown(app.OceanTab);
            app.EnviromentModeDropDown.Items = {'Constant', 'Gradient ', 'Stochastic '};
            app.EnviromentModeDropDown.ValueChangedFcn = createCallbackFcn(app, @EnviromentModeDropDownValueChanged, true);
            app.EnviromentModeDropDown.Value = 'Constant';
            app.CurentHeadingEditFieldLabel = uilabel(app.OceanTab);
            app.CurentHeadingEditFieldLabel.Text = 'Curent Heading';
            app.CurentHeadingEditField = uieditfield(app.OceanTab, 'numeric');
            app.CurentHeadingEditField.Limits = [0 360];
            app.CurentHeadingEditField.ValueChangedFcn = createCallbackFcn(app, @CurentHeadingEditFieldValueChanged, true);
            app.CurentHeadingEditField.Value = 45;
            app.CurrentSpeedEditFieldLabel = uilabel(app.OceanTab);
            app.CurrentSpeedEditFieldLabel.Text = 'Current Speed';
            app.CurrentSpeedEditField = uieditfield(app.OceanTab, 'numeric');
            app.CurrentSpeedEditField.Limits = [0 Inf];
            app.CurrentSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentSpeedEditFieldValueChanged, true);
            app.CurrentSpeedEditField.Value = 0.5;
            app.ShowCompassCheckBox = uicheckbox(app.OceanTab);
            app.ShowCompassCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCompassCheckBoxValueChanged, true);
            app.ShowCompassCheckBox.Text = 'Show Compass';
            app.ConsoleTextArea = uitextarea(app.GridLayoutEast);
            app.ConsoleTextArea.Editable = 'off';
            app.ConsoleTextArea.Enable = 'off';
            app.ConsoleTextArea.Visible = 'off';
            app.GridLayoutWest = uigridlayout(app.GridLayout);
            app.SideViewAxes = uiaxes(app.GridLayoutWest);
            title(app.SideViewAxes, 'Tactical View Display')
            app.SideViewAxes.XTick = [];
            app.SideViewAxes.YTick = [];
            app.SideViewAxes.Visible = 'off';
            app.UIAxes = uiaxes(app.GridLayoutWest);
            app.UIAxes.XLim = [0 6];
            app.UIAxes.YLim = [0 9];
            app.UIAxes.XTick = [0 1 2 3 4 5 6];
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7 8 9];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'};
            app.UIAxes.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.DebugMenu = uimenu(app.ContextMenu);
            app.DebugMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugMenuSelected, true);
            app.DebugMenu.Text = 'Debug';
            app.UnlockMenu = uimenu(app.ContextMenu);
            app.UnlockMenu.MenuSelectedFcn = createCallbackFcn(app, @UnlockMenuSelected, true);
            app.UnlockMenu.Text = 'Unlock';
            app.RRMenu = uimenu(app.ContextMenu);
            app.RRMenu.MenuSelectedFcn = createCallbackFcn(app, @RRMenuSelected, true);
            app.RRMenu.Text = 'RR';
            % Assign app.ContextMenu
            app.GridLayoutEast.ContextMenu = app.ContextMenu;
            app.TabGroup.ContextMenu = app.ContextMenu;
            app.SimulationTab.ContextMenu = app.ContextMenu;
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mobilemineSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end