classdef mobilemineSim_SMCC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayoutWest                 matlab.ui.container.GridLayout
        UIAxesSouth                    matlab.ui.control.UIAxes
        UIAxesNorth                    matlab.ui.control.UIAxes
        GridLayoutEast                 matlab.ui.container.GridLayout
        ConsoleTextArea                matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        Lamp                           matlab.ui.control.Lamp
        VerifyButton                   matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        AnimateCheckBox                matlab.ui.control.CheckBox
        StartButton                    matlab.ui.control.Button
        TimeLimitsEditField            matlab.ui.control.NumericEditField
        TimeLimitsEditFieldLabel       matlab.ui.control.Label
        NumRunsEdit                    matlab.ui.control.NumericEditField
        RunsLabel                      matlab.ui.control.Label
        FleetTab                       matlab.ui.container.Tab
        FleetBehaviorMenu              matlab.ui.control.DropDown
        BehaviorDropDown_2Label        matlab.ui.control.Label
        NumShipsEdit                   matlab.ui.control.NumericEditField
        ShipsLabel                     matlab.ui.control.Label
        MinefieldTab                   matlab.ui.container.Tab
        ImportButton                   matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        MineTypeDropDown               matlab.ui.control.DropDown
        MineTypeDropDownLabel          matlab.ui.control.Label
        DetectionRangemEditField       matlab.ui.control.NumericEditField
        DetectionRangemEditFieldLabel  matlab.ui.control.Label
        LayoutDropDown                 matlab.ui.control.DropDown
        LayoutDropDownLabel            matlab.ui.control.Label
        DamageRangemEditField          matlab.ui.control.NumericEditField
        DamageRangemEditFieldLabel     matlab.ui.control.Label
        MineCountEditField             matlab.ui.control.NumericEditField
        MineCountEditFieldLabel        matlab.ui.control.Label
        HTML                           matlab.ui.control.HTML
        ContextMenu                    matlab.ui.container.ContextMenu
        DebugMenu                      matlab.ui.container.Menu
        UnlockMenu                     matlab.ui.container.Menu
        RRMenu                         matlab.ui.container.Menu
    end

    
    properties (SetAccess = public)
        simEngine % SESimulatorEngine
    end
    
    methods (Access = public)        
        function setHtml(app, htmlContent)
            if nargin < 2 || isempty(htmlContent)
                htmlContent = '<i>Waiting for system data...</i>';
            end
            % This wraps your text in a basic "Dashboard" style
            htmlStr = sprintf(['<html><body style="font-family:Arial; padding:5px;">' ...
                               '<div style="border-left: 4px solid #0076a8; padding-left: 10px;">' ...
                               '%s</div></body></html>'], htmlContent);
            app.HTML.HTMLSource = htmlStr;
        end
        

        function numMines = getNumMines(app)
            numMines = app.MineCountEditField.Value;
        end

        function numShips = getNumShips(app)
            numShips = app.NumShipsEdit.Value;
        end

        function setNumShips(app, num_ships)            
            app.simEngine.setNumShips(num_ships);
            app.refreshDisplay();
        end

        function setNumMines(app, num_mines)
            app.simEngine.setNumMines(num_mines);
            app.refreshDisplay();
        end

        function didSet = setMinefieldLayout(app, layout)
            didSet = app.simEngine.setMinefieldLayout(layout);
            app.refreshDisplay();
        end

        function setFleetBehavior(app, behavior)
            app.simEngine.setFleetBehavior(behavior);
            app.refreshDisplay();
        end

        function refreshDisplay(app)
            app.simEngine.refreshDisplay();
        end

        function disableWidgets(app)
            app.setEnableWidgets(false);
            app.AnimateCheckBox.Enable="on";
            app.StopButton.Enable = "on";
            % app.StartButton.Enable = "on";
        end

        function enableWidgets(app)
            app.setEnableWidgets(true);
        end

        function setTitle(app, titleStr, varargin)
            titleStr = sprintf(titleStr, varargin{:});
            app.UIFigure.Name = titleStr;
        end

       function setMine_Type(app, mineType)
            % Sets the mine type in the simulation engine.
            app.simEngine.setMineType(mineType);
            
            % Check if this mine type exists in the dropdown's items.
            % The 'strcmpi' function provides a case-insensitive comparison.
            isMineTypeInUI = any(strcmpi(app.MineTypeDropDown.Items, mineType));
            
            if isMineTypeInUI
                % If the mine type is in the UI dropdown, update the UI.
                app.MineTypeDropDown.Value = mineType;
            else
                % If the mine type is not in the UI, log a warning.
                % This is expected if the test uses a type not in the dropdown.
                app.simEngine.logStatus('Note: Mine type "%s" is set programmatically and not available in the UI dropdown.', mineType);
        end
            
            % Pause to allow the app to process any pending UI updates.
            drawnow;
        end

        function mineType = getMine_Type(app)
            % Gets the current mine type from the simulation engine's minefield.
            mineType = app.simEngine.minefield.mineType;
        end

        function [didPass, results] = runVerification(app)
            % Runs the verification process defined in the simulation engine.
            [didPass, results] = app.simEngine.verify();
        end

        
        function setEnableWidgets(app, enabled)
            tabs = app.TabGroup.Children;
            if enabled
                enabledTag = 'on';
            else
                enabledTag = 'off';
            end
            for t=1:numel(tabs)
                tab = tabs(t);
                childrenH = tab.Children;
                for c=1:numel(childrenH)
                    h = childrenH(c);
                    if isprop(h,'enable')
                        set(h,'enable',enabledTag);
                    end
                end
            end
        end


        function updateStatistics(app)
            % TODO (Joe E) calculate current measures of effectiveness
            % Here is an example - you will need to make sure that
            % simEngine team has these methods - they will need to create
            % them if they don't
            stats = app.simEngine.getStatistics();

            %             num_mines = app.simEngine.numMines;
            %             num_ships = app.simEngine.numShips;
            %             num_mines_remaining = app.simEngine.getNumUnexplodedMines();
            %             num_ships_remaining = app.simEngine.getNumUnsunkShips();
            %
            %             moe_1 = nan;
            %             if num_ships > 0
            %                 moe_1 = 1 - num_ships_remaining / num_ships;
            %             end            
            % statsStr = sprintf('<b>MOE 1</b>%f<br>', moe_1);


            % Generate the HTML string
            htmlStr = sprintf(['<html>'...
                '<head><style>'...
                'table {font-family: Arial, sans-serif; border-collapse: collapse; width: 100%%;}'...
                'th, td {border: 1px solid #dddddd; text-align: left; padding: 8px;}'...
                'th {background-color: #f2f2f2;}'...
                '</style></head>'...
                '<body>'...
                '<h2>Statistics</h2>'...
                '<table>'...
                '<tr><th>Statistic</th><th>Value</th></tr>'...
                '<tr><td>Ships Remaining</td><td>%d</td></tr>'...
                '<tr><td>Ships Sunk</td><td>%d</td></tr>'...
                '<tr><td>Proportion of Ships Remaining</td><td>%.2f</td></tr>'...
                '<tr><td>Mines Remaining</td><td>%d</td></tr>'...                
                '<tr><td>Mines Detonated</td><td>%d</td></tr>'...
                '<tr><td>Proportion of Mines Remaining</td><td>%.2f</td></tr>'...
                '</table>'...
                '</body>'...
                '</html>'], stats.shipsRemaining, stats.shipsSunk, stats.proportionShipsRemaining, stats.minesRemaining, stats.minesDetonated, stats.proportionMinesRemaining);

            app.setHtml(htmlStr);
            % Generate the HTML string
            htmlString = sprintf(['<html>'...
                '<head><style>'...
                'table {font-size: 12px; font-family: Arial, sans-serif; border-collapse: collapse; width: 100%%;}'...
                'th, td {border: 1px solid #dddddd; text-align: right; padding: 8px;}'...
                'th {background-color: #f2f2f2;}'...
                'h2 {font-family: Arial, sans-serif;}'...
                '</style></head>'...
                '<body>'...
                '<h2></h2>'...
                '<table>'...
                '<tr><th></th><th>Ships</th><th>Mines</th></tr>'...
                '<tr><td><b>Remaining</b></td><td>%d</td><td>%d</td></tr>'...
                '<tr><td><b>Destroyed</b></td><td>%d</td><td>%d</td></tr>'...
                '<tr><td><b>Percent Remaining</b></td><td>%.1f%%</td><td>%.1f%%</td></tr>'...
                '</table>'...
                '</body>'...
                '</html>'], ...
                stats.shipsRemaining, stats.minesRemaining, ...
                stats.shipsSunk, stats.minesDetonated, ...
                stats.proportionShipsRemaining*100, stats.proportionMinesRemaining*100);
            app.setHtml(htmlString);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Initial Setup
            logger = Logger.getInstance();
            logger.setTextHandle(app.ConsoleTextArea);
            app.simEngine = SESimulatorEngine();
            app.setTitle('NPS Mine Simulator - Capstone Integration');
            
            % 2. CRITICAL: SET BOUNDARIES FIRST
            % The engine needs these to calculate mine positions
            % (Standard: [x, y, width, height])
            boundary_box = [0, 0, 6, 9]; % Starts at (0,0), 6 units wide, 9 units high
            app.simEngine.setBoundaryBox(boundary_box);
            
            minefield_box = [1.5, 1.5, 3, 6]; % Starts at (1.5, 1.5), 3 units wide, 6 units high
            app.simEngine.setMinefieldBox(minefield_box);

            % 3. Setup UI Components
            app.UIAxesSouth.Visible = 'on'; 
            app.Lamp.Color = [0.5 0.5 0.5]; 
            app.ConsoleTextArea.Visible = 'on';
            app.ConsoleTextArea.Enable = 'on';
            
            % 4. Sync Dropdowns
            app.FleetBehaviorMenu.Items = app.simEngine.getValidFleetBehaviors();
            app.LayoutDropDown.Items = app.simEngine.getValidMinefieldLayouts();
            app.simEngine.setAxesHandle(app.UIAxesSouth);
            
            % 5. NOW SET LAYOUT AND COUNTS
            % Now that boundary_box exists, these won't crash
            app.setFleetBehavior(app.FleetBehaviorMenu.Value);
            app.setMinefieldLayout(app.LayoutDropDown.Value); % <--- This was crashing
            app.simEngine.setMineType(app.MineTypeDropDown.Value);
            app.setNumMines(app.getNumMines());
            app.setNumShips(app.getNumShips());
            
            app.setHtml('System Ready');
            logger.log('INFO', 'GUI Layer: Startup complete with Boundaries initialized.');
        end

        % Value changed function: NumRunsEdit
        function NumRunsEditCb(app, event)
            value = app.NumRunsEdit.Value;
            app.simEngine.setNumRuns(value);
        end

        % Value changed function: TimeLimitsEditField
        function TimeLimitsEditFieldCb(app, event)
            value = app.TimeLimitsEditField.Value;
            app.simEngine.setTimeLimit(value);
        end

        % Button pushed function: StartButton
        function StartButtonPushedCb(app, event)
            %PENDING TO ADD THIS FUNCTION TO RUN THE SIMULATION
            app.disableWidgets();
            app.setHtml('Running');
            app.simEngine.run();
            app.updateStatistics();            
            app.enableWidgets();
        end

        % Value changed function: NumShipsEdit
        function NumShipsEditValueChanged(app, event)
            value = app.NumShipsEdit.Value;
            app.setNumShips(value);
            % fprintf(1,'Number of ships changed to: %d\n', value);
        end

        % Value changed function: FleetBehaviorMenu
        function FleetBehaviorMenuValueChanged(app, event)
            value = app.FleetBehaviorMenu.Value;
            app.setFleetBehavior(value);
            % fprintf(1,'Fleet behavior is now: %s\n', value);
        end

        % Value changed function: MineCountEditField
        function MineCountEditFieldValueChanged(app, event)
            value = app.MineCountEditField.Value;
            app.setNumMines(value);
        end

        % Value changed function: LayoutDropDown
        function LayoutDropDownCb(app, event)
            value = app.LayoutDropDown.Value;
            app.setMinefieldLayout(value);            
        end

        % Menu selected function: UnlockMenu
        function UnlockMenuSelected(app, event)
            app.enableWidgets();
        end

        % Menu selected function: RRMenu
        function RRMenuSelected(app, event)
            web('https://youtu.be/dQw4w9WgXcQ?si=P4Xfbnwc87U4JXh8');
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            data = struct( ...
                'MineCount', app.MineCountEditField.Value, ...
                'Layout', app.LayoutDropDown.Value, ...
                'DamageRange_m', app.DamageRangemEditField.Value, ...
                'DetectionRange_m', app.DetectionRangemEditField.Value, ...
                'MineType', app.MineTypeDropDown.Value ...
                );
            [file, path] = uiputfile('*.json', 'Save Minefield Configuration');
            if file
                jsonText = jsonencode(data);
                fid = fopen(fullfile(path, file), 'w');
                if fid ~= -1
                    fwrite(fid, jsonText, 'char');
                    fclose(fid);
                end
            end
            figure(app.UIFigure); % Brings the UIFigure to focus
            app.UIFigure.Visible = 'on'; % Ensure it's visible
            drawnow; % Force update of graphics
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file, path] = uigetfile('*.json', 'Select Minefield Configuration');
            if isequal(file, 0)
                return; % User canceled
            end

            fullFilePath = fullfile(path, file);

            % Read and decode JSON
            fid = fopen(fullFilePath, 'r');
            if fid == -1
                uialert(app.UIFigure, 'Unable to open the file.', 'File Error');
                return;
            end

            raw = fread(fid, inf, 'char=>char')';
            fclose(fid);

            try
                data = jsondecode(raw);

                % Assign values to UI components
                if isfield(data, 'MineCount')
                    app.MineCountEditField.Value = data.MineCount;
                end
                if isfield(data, 'Layout')
                    app.LayoutDropDown.Value = data.Layout;
                end
                if isfield(data, 'DamageRange_m')
                    app.DamageRangemEditField.Value = data.DamageRange_m;
                end
                if isfield(data, 'DetectionRange_m')
                    app.DetectionRangemEditField.Value = data.DetectionRange_m;
                end
                if isfield(data, 'MineType')
                    app.MineTypeDropDown.Value = data.MineType;
                end

            catch ME
                uialert(app.UIFigure, ['Error reading JSON: ' ME.message], 'Import Error');
            end
            value = app.MineCountEditField.Value;
            app.setNumMines(value);
            value = app.LayoutDropDown.Value;
            app.setMinefieldLayout(value);
            app.refreshDisplay();
            figure(app.UIFigure); % Brings the UIFigure to focus
            app.UIFigure.Visible = 'on'; % Ensure it's visible
            drawnow; % Force update of graphics

        end

        % Drop down opening function: LayoutDropDown
        function LayoutDropDownOpening(app, event)
            
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            if app.simEngine.debugMode
                app.DebugMenu.Checked = 'on';
            else
                app.DebugMenu.Checked = 'off';
            end
        end

        % Menu selected function: DebugMenu
        function DebugMenuSelected(app, event)
            % toggle debug mode
            app.simEngine.setDebugMode(~app.simEngine.debugMode);

            % update check mark
            if app.simEngine.debugMode
                % app.DebugMenu.Checked = 'on';
                app.ConsoleTextArea.Visible = 'on';
            else
                % app.DebugMenu.Checked = 'off';
                app.ConsoleTextArea.Visible = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if ~isempty(app.simEngine)
                    app.simEngine.isRunning = false;
                end
            catch
            end
            delete(app)            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.simEngine.stop();
        end

        % Value changed function: MineTypeDropDown
        function MineTypeDropDownValueChanged(app, event)
            value = app.MineTypeDropDown.Value;
            app.simEngine.setMineType(value);
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            logger = Logger.getInstance();
            app.Lamp.Color = [1 0.65 0]; % Amber
            logger.log('INFO', 'GUI: Initiating System Verification...');
            drawnow;
        
            if ~isempty(app.simEngine)
                try
                    [pass, details] = app.simEngine.verify(); 
                    
                    if pass
                        app.Lamp.Color = 'green';
                        % This is the "New Code" that goes into the HTML box
                        report = sprintf('<h3 style="color:green;">Verification: PASSED</h3><p>%s</p>', details);
                    else
                        app.Lamp.Color = 'red';
                        report = sprintf('<h3 style="color:red;">Verification: FAILED</h3><p>%s</p>', details);
                    end
                    
                    % Call the helper to display it
                    app.setHtml(report);
                    logger.log('INFO', 'VERIFICATION COMPLETE: %s', details);
                    
                catch ME
                    app.Lamp.Color = 'red';
                    app.setHtml('<b style="color:red;">Contract Error:</b> Method not found.');
                    logger.log('ERROR', 'GUI: Interface Contract Error - %s', ME.message);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayoutEast = uigridlayout(app.GridLayout);
            app.HTML = uihtml(app.GridLayoutEast);
            app.TabGroup = uitabgroup(app.GridLayoutEast);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.RunsLabel = uilabel(app.SimulationTab);
            app.RunsLabel.Text = 'Number of Runs';
            app.NumRunsEdit = uieditfield(app.SimulationTab, 'numeric');
            app.NumRunsEdit.ValueChangedFcn = createCallbackFcn(app, @NumRunsEditCb, true);
            app.NumRunsEdit.Value = 1;
            app.TimeLimitsEditFieldLabel = uilabel(app.SimulationTab);
            app.TimeLimitsEditFieldLabel.Text = 'Time Limit (s)';
            app.TimeLimitsEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TimeLimitsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLimitsEditFieldCb, true);
            app.TimeLimitsEditField.Value = 30;
            app.StartButton = uibutton(app.SimulationTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushedCb, true);
            app.StartButton.Text = 'Start';
            app.AnimateCheckBox = uicheckbox(app.SimulationTab);
            app.AnimateCheckBox.Text = 'Animate';
            app.AnimateCheckBox.Value = true;
            app.StopButton = uibutton(app.SimulationTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.VerifyButton = uibutton(app.SimulationTab, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.Lamp = uilamp(app.SimulationTab);
            app.FleetTab = uitab(app.TabGroup);
            app.FleetTab.Title = 'Fleet';
            app.ShipsLabel = uilabel(app.FleetTab);
            app.ShipsLabel.Text = 'Ships';
            app.NumShipsEdit = uieditfield(app.FleetTab, 'numeric');
            app.NumShipsEdit.ValueChangedFcn = createCallbackFcn(app, @NumShipsEditValueChanged, true);
            app.NumShipsEdit.Value = 10;
            app.BehaviorDropDown_2Label = uilabel(app.FleetTab);
            app.BehaviorDropDown_2Label.Text = 'Behavior';
            app.FleetBehaviorMenu = uidropdown(app.FleetTab);
            app.FleetBehaviorMenu.Items = {'Kamikaze', 'Random', 'Channelized'};
            app.FleetBehaviorMenu.ValueChangedFcn = createCallbackFcn(app, @FleetBehaviorMenuValueChanged, true);
            app.FleetBehaviorMenu.Value = 'Kamikaze';
            app.MinefieldTab = uitab(app.TabGroup);
            app.MinefieldTab.Title = 'Minefield';
            app.MineCountEditFieldLabel = uilabel(app.MinefieldTab);
            app.MineCountEditFieldLabel.Text = 'Mine Count';
            app.MineCountEditField = uieditfield(app.MinefieldTab, 'numeric');
            app.MineCountEditField.Limits = [0 10000];
            app.MineCountEditField.ValueChangedFcn = createCallbackFcn(app, @MineCountEditFieldValueChanged, true);
            app.MineCountEditField.Value = 10;
            app.DamageRangemEditFieldLabel = uilabel(app.MinefieldTab);
            app.DamageRangemEditFieldLabel.Text = 'Damage Range (m)';
            app.DamageRangemEditField = uieditfield(app.MinefieldTab, 'numeric');
            app.DamageRangemEditField.Value = 500;
            app.LayoutDropDownLabel = uilabel(app.MinefieldTab);
            app.LayoutDropDownLabel.Text = 'Layout';
            app.LayoutDropDown = uidropdown(app.MinefieldTab);
            app.LayoutDropDown.Items = {'Random', 'Smart', 'Dumb'};
            app.LayoutDropDown.DropDownOpeningFcn = createCallbackFcn(app, @LayoutDropDownOpening, true);
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @LayoutDropDownCb, true);
            app.LayoutDropDown.Value = 'Smart';
            app.DetectionRangemEditFieldLabel = uilabel(app.MinefieldTab);
            app.DetectionRangemEditFieldLabel.Text = 'Detection Range (m)';
            app.DetectionRangemEditField = uieditfield(app.MinefieldTab, 'numeric');
            app.DetectionRangemEditField.Value = 500;
            app.MineTypeDropDownLabel = uilabel(app.MinefieldTab);
            app.MineTypeDropDownLabel.Text = 'Mine Type';
            app.MineTypeDropDown = uidropdown(app.MinefieldTab);
            app.MineTypeDropDown.Items = {'Mobile', 'Static', 'Tethered', 'Detect_Release'};
            app.MineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MineTypeDropDownValueChanged, true);
            app.MineTypeDropDown.Value = 'Mobile';
            app.ExportButton = uibutton(app.MinefieldTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ImportButton = uibutton(app.MinefieldTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ConsoleTextArea = uitextarea(app.GridLayoutEast);
            app.ConsoleTextArea.Editable = 'off';
            app.ConsoleTextArea.Enable = 'off';
            app.ConsoleTextArea.Visible = 'off';
            app.GridLayoutWest = uigridlayout(app.GridLayout);
            app.UIAxesNorth = uiaxes(app.GridLayoutWest);
            zlabel(app.UIAxesNorth, 'Z')
            app.UIAxesNorth.XTick = [];
            app.UIAxesNorth.YTick = [];
            app.UIAxesNorth.Visible = 'off';
            app.UIAxesSouth = uiaxes(app.GridLayoutWest);
            app.UIAxesSouth.XLim = [0 6];
            app.UIAxesSouth.YLim = [0 9];
            app.UIAxesSouth.XTick = [0 1 2 3 4 5 6];
            app.UIAxesSouth.YTick = [0 1 2 3 4 5 6 7 8 9];
            app.UIAxesSouth.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'};
            app.UIAxesSouth.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.DebugMenu = uimenu(app.ContextMenu);
            app.DebugMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugMenuSelected, true);
            app.DebugMenu.Text = 'Debug';
            app.UnlockMenu = uimenu(app.ContextMenu);
            app.UnlockMenu.MenuSelectedFcn = createCallbackFcn(app, @UnlockMenuSelected, true);
            app.UnlockMenu.Text = 'Unlock';
            app.RRMenu = uimenu(app.ContextMenu);
            app.RRMenu.MenuSelectedFcn = createCallbackFcn(app, @RRMenuSelected, true);
            app.RRMenu.Text = 'RR';
            % Assign app.ContextMenu
            app.GridLayoutEast.ContextMenu = app.ContextMenu;
            app.TabGroup.ContextMenu = app.ContextMenu;
            app.SimulationTab.ContextMenu = app.ContextMenu;
            app.UIAxesSouth.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mobilemineSim_SMCC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end