classdef verifyMineDetectionRanges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VerificationAppMineDetectionRangeUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        MineDetectionRangePanel    matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        DetectionRangeSlider       matlab.ui.control.Slider
        DetectionRangeSliderLabel  matlab.ui.control.Label
        VerifyButton               matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        ConsolePanel               matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        TextArea                   matlab.ui.control.TextArea
    end

    properties (Access = private)
        staticMine % Description - instance of SEMine
    end    
    
    methods (Access = public)
        
        function setDetectionRange(app, value)
            logger = Logger.getInstance();

            app.DetectionRangeSlider.Value = value;
            app.staticMine.setDetectRange(value);
            app.staticMine.update(nan, [], []);

            logger.log('INFO', 'Detection range set to %.1f units.', ...
                app.staticMine.detectRange);
        end

        function value = getDetectionRange(app)
            value = app.staticMine.detectRange;
        end

        function [didPass, results] = runVerification(app)
            logger = Logger.getInstance();
            logger.log('INFO', 'Running application verification.');

            [didPass, results] = app.staticMine.verify();

            if didPass
                logger.log('INFO', 'Verification complete. All tests passed.\n%s', ...
                    results.summary);
            else
                logger.log('ERROR', 'Verification did not pass.\n%s', ...
                    results.summary);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            logger = Logger.getInstance();
            logger.setTextHandle(app.TextArea);
            app.staticMine = SEStaticMine(0,0,app.UIAxes);
            app.DetectionRangeSlider.Value = app.staticMine.detectRange;
            app.staticMine.logStatus('Static Mine constructed with detection range of %0.1f units',...
                app.staticMine.detectRange);            
        end

        % Value changed function: DetectionRangeSlider
        function DetectionRangeSliderValueChanged(app, event)
            app.setDetectionRange(app.DetectionRangeSlider.Value);

            % refactored out
            % value = app.DetectionRangeSlider.Value;
            % app.staticMine.setDetectRange(value);
            % app.staticMine.update();
            % app.staticMine.logStatus('detection range set to %0.1f units',app.staticMine.detectRange);
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            app.runVerification();           

            % Refactored out
            % [didPass, results] = app.staticMine.verify();
            % if ~didPass
            %     app.staticMine.logWarning('Verification did not pass\n%s',results.summary);
            %     results
            % else
            %     app.staticMine.logStatus('Verification complete. All tests passed.\n',results.summary);
            % end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VerificationAppMineDetectionRangeUIFigure = uifigure('Visible', 'off');
            app.VerificationAppMineDetectionRangeUIFigure.Name = 'Verification App - Mine Detection Range';
            app.GridLayout = uigridlayout(app.VerificationAppMineDetectionRangeUIFigure);
            app.ConsolePanel = uipanel(app.GridLayout);
            app.ConsolePanel.Title = 'Console';
            app.GridLayout3 = uigridlayout(app.ConsolePanel);
            app.TextArea = uitextarea(app.GridLayout3);
            app.MineDetectionRangePanel = uipanel(app.GridLayout);
            app.MineDetectionRangePanel.Title = 'Mine Detection Range ';
            app.GridLayout2 = uigridlayout(app.MineDetectionRangePanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XLim = [-50 50];
            app.UIAxes.YLim = [-50 50];
            app.Panel = uipanel(app.GridLayout2);
            app.VerifyButton = uibutton(app.Panel, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.DetectionRangeSliderLabel = uilabel(app.Panel);
            app.DetectionRangeSliderLabel.Text = {'Detection '; 'Range'};
            app.DetectionRangeSlider = uislider(app.Panel);
            app.DetectionRangeSlider.ValueChangedFcn = createCallbackFcn(app, @DetectionRangeSliderValueChanged, true);
            % Show the figure after all components are created
            app.VerificationAppMineDetectionRangeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = verifyMineDetectionRanges

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VerificationAppMineDetectionRangeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VerificationAppMineDetectionRangeUIFigure)
        end
    end
end