classdef verifyOceanUserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResetButton                    matlab.ui.control.Button
        TimeLimitsEditField            matlab.ui.control.NumericEditField
        TimeLimitsEditFieldLabel       matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        CompassButton                  matlab.ui.control.Button
        VerificationLogPanel           matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
        VerifyButton                   matlab.ui.control.Button
        UserControlPanel               matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        OceanSettingsTab               matlab.ui.container.Tab
        DegreesLabel                   matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        OceanLamp                      matlab.ui.control.Lamp
        OceanLampLabel                 matlab.ui.control.Label
        CurrentSpeedEditField          matlab.ui.control.NumericEditField
        CurrentSpeedEditFieldLabel     matlab.ui.control.Label
        CurentHeadingEditField         matlab.ui.control.NumericEditField
        CurentHeadingEditFieldLabel    matlab.ui.control.Label
        OceanEnviromentModeDropDown    matlab.ui.control.DropDown
        OceanEnviromentModeDropDownLabel  matlab.ui.control.Label
        MineSettingsTab                matlab.ui.container.Tab
        TetherLengthEditField          matlab.ui.control.NumericEditField
        TetherLengthEditFieldLabel     matlab.ui.control.Label
        ImportButton                   matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        MineTypeDropDown               matlab.ui.control.DropDown
        MineTypeDropDownLabel          matlab.ui.control.Label
        MinefieldLayoutDropDown        matlab.ui.control.DropDown
        MinefieldLayoutDropDownLabel   matlab.ui.control.Label
        NumMinesEditField              matlab.ui.control.NumericEditField
        NumMinesLabel                  matlab.ui.control.Label
        MineModeDropDown               matlab.ui.control.DropDown
        MineModeDropDownLabel          matlab.ui.control.Label
        FleetSettingsTab               matlab.ui.container.Tab
        DetectionRangemEditField       matlab.ui.control.NumericEditField
        DetectionRangemEditFieldLabel  matlab.ui.control.Label
        DamageRangemEditField          matlab.ui.control.NumericEditField
        DamageRangemEditFieldLabel     matlab.ui.control.Label
        NumShipsEditField              matlab.ui.control.NumericEditField
        NumShipsLabel                  matlab.ui.control.Label
        FleetBehaviorDropDown          matlab.ui.control.DropDown
        FleetBehaviorDropDownLabel     matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        OceanEnabled logical = false
        OceanDirection double = 0
        OceanSpeed double = 0
        OceanMode string = "Constant"
        MineMode string = "Non-Tethered"
    
        Controller = []   % placeholder for controller/model connection later                                                                  
        
        simEngine % The core simulation engine 
        EnvModel %environment physics model
        OceanVectorGraphic % NEW: Handle for the compass arrow
        
    end

    methods (Access = public)

        function setOceanSpeed(app, value)
            app.CurrentSpeedEditField.Value = value;
        end
        function val = getOceanSpeed(app)
            val = app.CurrentSpeedEditField.Value;

        function setNumMines(app, value)
            app.NumMinesEditField.Value = value;
        end
        function val = getNumMines(app)
            val = app.NumMinesEditField.Value;
        end
         
       
        function setNumShips(app, value)
            app.NumShipsEditField.Value = value;
        end
        function val = getNumShips(app)
            val = app.NumShipsEditField.Value;
        end

        function [didPass, results] = runVerification(app)
            speedValid = app.CurrentSpeedEditField.Value > 0;
            minesValid = app.NumMinesEditField.Value <= 50;
            didPass = speedValid && minesValid;
            if didPass
                results.summary = sprintf('Verification Passed');
            else
                results.summary = 'Verification Failed';
            end
        end
        end
        
        function setEnvMode(app, modeStr)
            app.EnvModel.mode = modeStr;
        end
        function setEnvSpeed(app, speedVal)
            app.EnvModel.speed = speedVal;
            app.OceanSpeed = speedVal;
            %update visual
            app.CurrentSpeedEditField.Value = speedVal;
        end

        function [didPass, results] = runVerification(app)
            [didPass, msg] = app.EnvModel.verifyPhysics();
            results.summary = msg;
            logger = Logger.getInstance();
            logger.log('INFO', 'Verification Results: %s', msg);
        end
        
    end

        
        methods (Access = private)
        function syncUIState(app)
        app.OceanDirection = app.CurentHeadingEditField.Value;
        app.OceanSpeed = app.CurrentSpeedEditField.Value;
        app.OceanMode = string(app.OceanEnviromentModeDropDown.Value);
        app.MineMode = string(app.MineModeDropDown.Value);
        end

        function logMessage(app, msg)
        currentLog = app.TextArea.Value;

        if ischar(currentLog)
        currentLog = {currentLog};
        elseif isstring(currentLog)
        currentLog = cellstr(currentLog);
        end

        timestamp = datestr(now, 'HH:MM:SS');
        newEntry = sprintf('%s - %s', timestamp, msg);

        app.TextArea.Value = [currentLog; {newEntry}];
        end

        function updateOceanLamp(app)
        if ~app.OceanEnabled
                app.OceanLamp.Color = [0.5 0.5 0.5]; % Gray (Off)
            else
                app.OceanLamp.Color = [0 1 0]; % Green (Active)
            end
        end

       %function setOceanSpeed (app,speed)
       %    app.CurrentSpeedEditField.Value=speed;
       %    app.sendSettingsToController;
       %end 

       function sendSettingsToController(app)
           % 1. Create the Environment Object using GUI parameters
            bounds = app.simEngine.boundary_box;
            newEnv = SEEnvironment(bounds, app.OceanSpeed, app.OceanDirection, app.OceanMode);
            
            % (The override block that forced speed to 0 has been removed)
            
            % 2. Pass the fully built environment into the engine pipeline
            if ~isempty(app.simEngine)
                app.simEngine.setOceanEnvironment(newEnv);
            end
            
            % 3. Update the visual compass arrow on the map
            app.updateOceanVector();
       end


       function updateControlState(app)
        %if app.OceanEnabled
                app.OceanEnviromentModeDropDown.Enable = 'on';
                app.CurentHeadingEditField.Enable = 'on';
                app.CurrentSpeedEditField.Enable = 'on';
            %else
                %app.OceanEnviromentModeDropDown.Enable = 'off';
                %app.CurentHeadingEditField.Enable = 'off';
                %app.CurrentSpeedEditField.Enable = 'off';
            %end
            
            % (MineModeDropDown intentionally left out so it stays permanently disabled)

        app.MineModeDropDown.Enable = 'off';
        app.TetherLengthEditField.Enable = 'off';
       end 
        
      
        function updateOceanVector(app)
            % 1. Always clear the existing compass first
            if ~isempty(app.OceanVectorGraphic) && isvalid(app.OceanVectorGraphic)
                delete(app.OceanVectorGraphic);
            end

            % 2. If Ocean is OFF, stop here
            if ~app.OceanEnabled
                return;
            end

            % 3. Draw a fixed-size Compass Rose on the right side of the map
            anchorX = 5.25; % Anchored cleanly on the right side
            anchorY = 1.25; % Anchored near the bottom
            radius = 0.5;   % Fixed size, ignoring app.OceanSpeed entirely
            
            % Calculate U and V for the arrow direction ONLY
            U = radius * cosd(app.OceanDirection);
            V = radius * sind(app.OceanDirection);
            
            hold(app.UIAxes, 'on');
            
            % Group everything together so it deletes cleanly when toggled off
            app.OceanVectorGraphic = hggroup(app.UIAxes);
            
            % Draw the compass background ring
            theta = linspace(0, 2*pi, 100);
            plot(app.UIAxes, anchorX + radius*cos(theta), anchorY + radius*sin(theta), ...
                'Color', [0.7 0.7 0.7], 'LineWidth', 1.5, 'LineStyle', '--', ...
                'Parent', app.OceanVectorGraphic);
            
            % Add N, E, S, W labels
            text(app.UIAxes, anchorX, anchorY + radius + 0.2, 'N', ...
                'HorizontalAlignment', 'center', 'FontSize', 9, 'FontWeight', 'bold', 'Color', [0.3 0.3 0.3], 'Parent', app.OceanVectorGraphic);
            text(app.UIAxes, anchorX, anchorY - radius - 0.2, 'S', ...
                'HorizontalAlignment', 'center', 'FontSize', 9, 'FontWeight', 'bold', 'Color', [0.3 0.3 0.3], 'Parent', app.OceanVectorGraphic);
            text(app.UIAxes, anchorX + radius + 0.2, anchorY, 'E', ...
                'HorizontalAlignment', 'center', 'FontSize', 9, 'FontWeight', 'bold', 'Color', [0.3 0.3 0.3], 'Parent', app.OceanVectorGraphic);
            text(app.UIAxes, anchorX - radius - 0.2, anchorY, 'W', ...
                'HorizontalAlignment', 'center', 'FontSize', 9, 'FontWeight', 'bold', 'Color', [0.3 0.3 0.3], 'Parent', app.OceanVectorGraphic);
            
            % Draw the directional arrow
            % We set the 6th argument (AutoScale) to 0 so MATLAB doesn't resize it
            quiver(app.UIAxes, anchorX, anchorY, U, V, 0, ...
                'Color', [0 0.4470 0.7410], 'LineWidth', 3, 'MaxHeadSize', 1.5, ...
                'Parent', app.OceanVectorGraphic);
                
            hold(app.UIAxes, 'off');
        end

        
       end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % --- FIX 1: Set default GUI state to Ocean Enabled ---
            app.OceanEnabled = true; 
            app.OceanDirection = app.CurentHeadingEditField.Value;
            app.OceanSpeed = app.CurrentSpeedEditField.Value;
            app.OceanMode = string(app.OceanEnviromentModeDropDown.Value);
            app.MineMode = string(app.MineModeDropDown.Value);

            % Set lamp to active state initially
            app.OceanLamp.Color = [0 1 0]; % Green

            % Initialize log
            app.TextArea.Value = {'Application started.';
                                 'Ocean environment enabled by default.'};

            % --- ENGINE INITIALIZATION ---
            app.simEngine = SESimulatorEngine();
            % Map the simulation to the OceanUserInterface Axes
            app.simEngine.setAxesHandle(app.UIAxes);

            % --- Ocean initialization ---
            app.EnvModel = SEEnvironment([0 0.1 6 8.8], app.OceanSpeed, app.OceanDirection, app.OceanMode);
            app.simEngine.setAxesHandle(app.UIAxes);

            % Set standard boundaries
            boundary_box = [0, 0.1, 6, 9-0.1];
            app.simEngine.setBoundaryBox(boundary_box);
            minefield_box = [1.5, 1.5, 6-1.5*2, 9-1.5*2];
            app.simEngine.setMinefieldBox(minefield_box);
            
            % Axes setup
            title(app.UIAxes, 'Mine Simulation Environment')
            xlim(app.UIAxes, [0 6])
            ylim(app.UIAxes, [0 9])
            grid(app.UIAxes, 'on')
            
            app.simEngine.logStatus('Engine initialized in OceanUserInterface.');

            % Hard-set the items to match the Engine's Constant properties
            app.FleetBehaviorDropDown.Items = {'Kamikaze', 'Random', 'Channelized'};
            app.MinefieldLayoutDropDown.Items = {'uniform', 'rand', 'randn', 'uniform-e'};
            
            % Ensure the UI starts with the first item selected
            app.FleetBehaviorDropDown.Value = 'Kamikaze';
            app.MinefieldLayoutDropDown.Value = 'uniform';

            % Initial control state
            app.updateControlState();

           % --- FIX 2: Push UI defaults directly to the Engine ---
            app.simEngine.setNumMines(app.NumMinesEditField.Value);
            app.simEngine.setNumShips(app.NumShipsEditField.Value);
            app.simEngine.setMineType(app.MineTypeDropDown.Value);
            app.simEngine.setMinefieldLayout(app.MinefieldLayoutDropDown.Value);
            app.simEngine.setFleetBehavior(app.FleetBehaviorDropDown.Value);

            % Safely handle the broken backend methods 
            try
                app.simEngine.setDamageRadius(app.DamageRangemEditField.Value);
                app.simEngine.setdetectRange(app.DetectionRangemEditField.Value);
            catch
                app.logMessage('Warning: Backend does not support dynamic Damage/Detection ranges yet.');
            end

            % Finally, send the enabled ocean settings to draw the compass
            app.sendSettingsToController();
        
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            app.NumMinesEditField.Value = 10;
            app.NumShipsEditField.Value = 10;
            app.CurrentSpeedEditField.Value = 2;
            app.MineTypeDropDown.Value = 'Static';
            
            app.syncUIState();

            app.simEngine.setNumShips(app.NumShipsEditField.Value);
            app.simEngine.setNumMines(app.NumMinesEditField.Value);

            app.logMessage('Running GUI & Engine verification...');

            % --- Ocean Environment Checks ---
            if app.OceanDirection < 0 || app.OceanDirection > 360
                app.logMessage('FAIL: Direction must be between 0 and 360.');
                return;
            end
            if app.OceanSpeed < 0
                app.logMessage('FAIL: Speed must be non-negative.');
                return;
            end

            % --- NEW: Simulation Engine Checks ---
            if isempty(app.simEngine)
                app.logMessage('FAIL: Simulation Engine is not initialized!');
                return;
            end
            
            if app.simEngine.getNumMines() <= 0
                app.logMessage('WARNING: Running with 0 mines.');
            end
            
            if app.simEngine.getNumShips() <= 0
                app.logMessage('FAIL: Cannot run with 0 ships.');
                return;
            end

            % --- PASS Output ---
            app.logMessage(['PASS: Ocean enabled = ' char(string(app.OceanEnabled))]);
            app.logMessage(sprintf('PASS: Direction = %.2f deg | Speed = %.2f', app.OceanDirection, app.OceanSpeed));
            app.logMessage(sprintf('PASS: Engine verified with %d ships and %d mines.', app.simEngine.getNumShips(), app.simEngine.getNumMines()));
            app.logMessage('Verification complete. Ready to start.');

            [passed, results] = app.runVerification();

            %if ~passed
            %    app.logMessage(['FAIL: ' results.summary]);
            %    uialert(app.UIFigure, results.summary, 'Physics Verification Failed');
            %    return;
            %end

            % --- PASS Output ---
            app.logMessage(['PASS: ' results.summary]);
            app.logMessage('Verification complete. Ready to start.');
        end

        % Callback function
        function TurnOnButtonPushed(app, event)
        app.OceanEnabled = ~app.OceanEnabled;

        if app.OceanEnabled
        app.CompassButton.Text = 'Turn Off';
        app.logMessage('Ocean environment enabled.');
        else
        app.CompassButton.Text = 'Turn On';
        app.logMessage('Ocean environment disabled.');
        end

        app.updateOceanLamp();
        app.updateControlState();
        app.sendSettingsToController();    
        end

        % Callback function
        function DirectionEditFieldValueChanged(app, event)
        app.syncUIState();
        app.updateOceanLamp();
        app.sendSettingsToController();
        app.logMessage(sprintf('Direction set to %.2f degrees.', app.OceanDirection));      
        end

        % Callback function
        function SpeedEditFieldValueChanged(app, event)
        app.syncUIState();
        app.updateOceanLamp();
        app.sendSettingsToController();
        app.logMessage(sprintf('Speed set to %.2f.', app.OceanSpeed));      
        end

        % Callback function
        function ModeDropDownValueChanged(app, event)
        app.syncUIState();
        app.logMessage(['Ocean mode changed to: ' char(app.OceanMode)]);
        app.sendSettingsToController();
        end

        % Callback function
        function MineModeDropDownValueChanged(app, event)
        app.syncUIState();
        app.logMessage(['Mine mode changed to: ' char(app.MineMode)]);
        app.sendSettingsToController();        
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           app.logMessage('Simulation starting...');
            
            % 1. Sync the latest GUI parameters to the engine
            app.syncUIState();
            app.sendSettingsToController();
            
            % 2. (Optional) Disable controls so user can't break physics mid-run
            app.CompassButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            
            % 3. Run the engine (this triggers the while loop in SESimulatorEngine)
            app.simEngine.run(1); 
            
            % 4. Re-enable controls when finished
            app.CompassButton.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.logMessage('Simulation run complete.'); 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.logMessage('Sending stop signal to engine...');
            
            % This flips the isRunning flag to false, breaking the engine's while loop
            if ~isempty(app.simEngine)
                app.simEngine.stop();
            end
        end

        % Value changed function: NumMinesEditField
        function NumMinesEditFieldValueChanged(app, event)
            value = app.NumMinesEditField.Value;
            app.simEngine.setNumMines(value);
            app.simEngine.refreshDisplay();
            app.logMessage(sprintf('Mine count set to: %d', value));
        end

        % Value changed function: NumShipsEditField
        function NumShipsEditFieldValueChanged(app, event)
            value = app.NumShipsEditField.Value;
            app.simEngine.setNumShips(value);
            app.simEngine.refreshDisplay();
            app.logMessage(sprintf('Ship count set to: %d', value));
          
        end

        % Value changed function: MinefieldLayoutDropDown
        function MinefieldLayoutDropDownValueChanged(app, event)
            value = app.MinefieldLayoutDropDown.Value;
            app.simEngine.setMinefieldLayout(value);
            app.simEngine.refreshDisplay();
            app.logMessage(['Minefield layout set to: ' char(value)]);
        end

        % Value changed function: DamageRangemEditField
        function DamageRangemEditFieldValueChanged(app, event)
            value = app.DamageRangemEditField.Value;
            try
                app.simEngine.setDamageRadius(value);
                app.logMessage(sprintf('Damage range set to: %.1f m', value));
            catch
                app.logMessage('Error: Backend missing setDamageRadius. Range not updated.');
            end
        end

        % Value changed function: DetectionRangemEditField
        function DetectionRangemEditFieldValueChanged(app, event)
            value = app.DetectionRangemEditField.Value;
            app.simEngine.setdetectRange(value);
            app.logMessage(sprintf('Detection range set to: %.1f m', value));
        end

        % Value changed function: TimeLimitsEditField
        function TimeLimitsEditFieldValueChanged(app, event)
            value = app.DetectionRangemEditField.Value;
            try
                app.simEngine.setdetectRange(value);
                app.logMessage(sprintf('Detection range set to: %.1f m', value));
            catch
                app.logMessage('Error: Backend missing setdetectRange. Range not updated.');
            end
        end

        % Value changed function: MineTypeDropDown
        function MineTypeDropDownValueChanged(app, event)
            value = app.MineTypeDropDown.Value;
            app.simEngine.setMineType(value);
            app.simEngine.refreshDisplay();
            app.logMessage(['Mine type set to: ' char(value)]);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Gather current UI data [cite: 135, 136]
            data = struct( ...
                'MineCount', app.NumMinesEditField.Value, ...
                'Layout', app.MinefieldLayoutDropDown.Value, ...
                'DamageRange_m', app.DamageRangemEditField.Value, ...
                'DetectionRange_m', app.DetectionRangemEditField.Value, ...
                'MineType', app.MineTypeDropDown.Value ...
                );
            
            % Save to JSON [cite: 137]
            [file, path] = uiputfile('*.json', 'Save Minefield Configuration');
            if file
                jsonText = jsonencode(data);
                fid = fopen(fullfile(path, file), 'w'); [cite: 138]
                if fid ~= -1
                    fwrite(fid, jsonText, 'char');
                    fclose(fid); [cite: 139]
                    app.logMessage(['Exported config to ' file]);
                end
            end
            
            % Bring UI back to focus [cite: 140, 141, 142]
            figure(app.UIFigure);
            app.UIFigure.Visible = 'on';
            drawnow;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Select and read file [cite: 142, 143, 144, 145]
            [file, path] = uigetfile('*.json', 'Select Minefield Configuration');
            if isequal(file, 0)
                return;
            end

            fid = fopen(fullfile(path, file), 'r'); [cite: 145]
            if fid == -1
                uialert(app.UIFigure, 'Unable to open the file.', 'File Error'); [cite: 146]
                return;
            end

            raw = fread(fid, inf, 'char=>char')';
            fclose(fid); [cite: 147]

            % Parse JSON and update UI components [cite: 148, 149]
            try
                data = jsondecode(raw);
                if isfield(data, 'MineCount')
                    app.NumMinesEditField.Value = data.MineCount;
                    app.simEngine.setNumMines(data.MineCount);
                end
                if isfield(data, 'Layout')
                    app.MinefieldLayoutDropDown.Value = data.Layout;
                    app.simEngine.setMinefieldLayout(data.Layout);
                end
                if isfield(data, 'DamageRange_m')
                    app.DamageRangemEditField.Value = data.DamageRange_m;
                    app.simEngine.setDamageRadius(data.DamageRange_m);
                end
                if isfield(data, 'DetectionRange_m')
                    app.DetectionRangemEditField.Value = data.DetectionRange_m;
                    app.simEngine.setdetectRange(data.DetectionRange_m);
                end
                if isfield(data, 'MineType')
                    app.MineTypeDropDown.Value = data.MineType;
                    app.simEngine.setMineType(data.MineType);
                end
                
                app.logMessage(['Imported config from ' file]);
                app.simEngine.refreshDisplay(); [cite: 156]

            catch ME
                uialert(app.UIFigure, ['Error reading JSON: ' ME.message], 'Import Error'); [cite: 154, 155]
            end
            
            % Bring UI back to focus [cite: 156, 157, 158]
            figure(app.UIFigure); 
            app.UIFigure.Visible = 'on';
            drawnow;
        end

        % Value changed function: FleetBehaviorDropDown
        function FleetBehaviorDropDownValueChanged(app, event)
            value = app.FleetBehaviorDropDown.Value;
            app.simEngine.setFleetBehavior(value);
            app.simEngine.refreshDisplay();
            app.logMessage(['Fleet behavior set to: ' char(value)]);
           
        end

        % Value changed function: CurrentSpeedEditField
        function CurrentSpeedEditFieldValueChanged(app, event)
            value = app.CurrentSpeedEditField.Value;
            app.setOceanSpeed(value);
        end

        % Button pushed function: CompassButton
        function CompassButtonPushed(app, event)
            % Toggle the boolean state
            app.OceanEnabled = ~app.OceanEnabled;
            
            if app.OceanEnabled
                app.logMessage('Ocean environment / Compass enabled.');
            else
                app.logMessage('Ocean environment / Compass disabled.');
            end
            
            % Trigger the visual updates
            app.updateOceanLamp();
            app.updateControlState();
            
            % Push to engine and draw/delete the vector arrow
            app.sendSettingsToController();
        end

        % Value changed function: CurentHeadingEditField
        function CurentHeadingEditFieldValueChanged(app, event)
            % 1. Pull the fresh values from the GUI
            app.syncUIState();
            
            % 2. Pass them to the controller (which automatically redraws the compass!)
            app.sendSettingsToController();
            
            app.logMessage(sprintf('Direction updated to %.2f degrees.', app.OceanDirection));
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.logMessage('Resetting application to default launch state...');
            
            % 1. Restore default GUI values based on initial startup states
            app.OceanEnabled = true;
            app.CurrentSpeedEditField.Value = 5;
            app.CurentHeadingEditField.Value = 90;
            app.OceanEnviromentModeDropDown.Value = 'Constant';
            app.NumMinesEditField.Value = 2;
            app.NumShipsEditField.Value = 1;
            app.MineTypeDropDown.Value = 'Mobile';
            app.MinefieldLayoutDropDown.Value = 'uniform';
            app.FleetBehaviorDropDown.Value = 'Kamikaze';
            app.DamageRangemEditField.Value = 500;
            app.DetectionRangemEditField.Value = 500;

            % 2. Sync the UI State
            app.syncUIState();
            
            % 3. Push restored defaults back to the Engine
            app.simEngine.setNumMines(app.NumMinesEditField.Value);
            app.simEngine.setNumShips(app.NumShipsEditField.Value);
            app.simEngine.setMineType(app.MineTypeDropDown.Value);
            app.simEngine.setMinefieldLayout(app.MinefieldLayoutDropDown.Value);
            app.simEngine.setFleetBehavior(app.FleetBehaviorDropDown.Value);
            
            try
                app.simEngine.setDamageRadius(app.DamageRangemEditField.Value);
                app.simEngine.setdetectRange(app.DetectionRangemEditField.Value);
            catch
                % Silently catch the backend limitation we worked around earlier
            end

            % --- NEW FIX: WIPE THE GRAPH CLEAN ---
            % 4. Clear the axes and reapply standard formatting 
            cla(app.UIAxes);
            title(app.UIAxes, 'Mine Simulation Environment');
            xlim(app.UIAxes, [0 6]);
            ylim(app.UIAxes, [0 9]);
            grid(app.UIAxes, 'on');

            % 5. Hard reset the simulation entities (moves everything to start)
            app.simEngine.reset();
            
            % 6. Redraw the visual environment and compass
            app.simEngine.refreshDisplay();
            app.updateOceanLamp();
            app.updateControlState();
            app.sendSettingsToController();
            
            app.logMessage('Reset complete.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mine Simulation Environment ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UserControlPanel = uipanel(app.UIFigure);
            app.UserControlPanel.Title = 'User Control Panel';
            app.TabGroup = uitabgroup(app.UserControlPanel);
            app.OceanSettingsTab = uitab(app.TabGroup);
            app.OceanSettingsTab.Title = 'Ocean Settings';
            app.OceanEnviromentModeDropDownLabel = uilabel(app.OceanSettingsTab);
            app.OceanEnviromentModeDropDownLabel.Text = 'Ocean Enviroment Mode';
            app.OceanEnviromentModeDropDown = uidropdown(app.OceanSettingsTab);
            app.OceanEnviromentModeDropDown.Items = {'Constant', 'Gradient ', 'Stochastic '};
            app.OceanEnviromentModeDropDown.Value = 'Constant';
            app.CurentHeadingEditFieldLabel = uilabel(app.OceanSettingsTab);
            app.CurentHeadingEditFieldLabel.Text = 'Curent Heading';
            app.CurentHeadingEditField = uieditfield(app.OceanSettingsTab, 'numeric');
            app.CurentHeadingEditField.Limits = [0 360];
            app.CurentHeadingEditField.ValueChangedFcn = createCallbackFcn(app, @CurentHeadingEditFieldValueChanged, true);
            app.CurentHeadingEditField.Value = 90;
            app.CurrentSpeedEditFieldLabel = uilabel(app.OceanSettingsTab);
            app.CurrentSpeedEditFieldLabel.Text = 'Current Speed';
            app.CurrentSpeedEditField = uieditfield(app.OceanSettingsTab, 'numeric');
            app.CurrentSpeedEditField.Limits = [0 Inf];
            app.CurrentSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentSpeedEditFieldValueChanged, true);
            app.CurrentSpeedEditField.Value = 5;
            app.OceanLampLabel = uilabel(app.OceanSettingsTab);
            app.OceanLampLabel.Text = 'Ocean Lamp';
            app.OceanLamp = uilamp(app.OceanSettingsTab);
            app.msLabel = uilabel(app.OceanSettingsTab);
            app.msLabel.Text = 'm/s';
            app.DegreesLabel = uilabel(app.OceanSettingsTab);
            app.DegreesLabel.Text = 'Degrees';
            app.MineSettingsTab = uitab(app.TabGroup);
            app.MineSettingsTab.Title = 'Mine Settings';
            app.MineModeDropDownLabel = uilabel(app.MineSettingsTab);
            app.MineModeDropDownLabel.Text = 'Mine Mode';
            app.MineModeDropDown = uidropdown(app.MineSettingsTab);
            app.MineModeDropDown.Items = {'Non-Tethered ', 'Tethered'};
            app.MineModeDropDown.Value = 'Non-Tethered ';
            app.NumMinesLabel = uilabel(app.MineSettingsTab);
            app.NumMinesLabel.Text = 'NumMines';
            app.NumMinesEditField = uieditfield(app.MineSettingsTab, 'numeric');
            app.NumMinesEditField.Limits = [0 50];
            app.NumMinesEditField.ValueChangedFcn = createCallbackFcn(app, @NumMinesEditFieldValueChanged, true);
            app.NumMinesEditField.Value = 2;
            app.MinefieldLayoutDropDownLabel = uilabel(app.MineSettingsTab);
            app.MinefieldLayoutDropDownLabel.Text = 'MinefieldLayout';
            app.MinefieldLayoutDropDown = uidropdown(app.MineSettingsTab);
            app.MinefieldLayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @MinefieldLayoutDropDownValueChanged, true);
            app.MineTypeDropDownLabel = uilabel(app.MineSettingsTab);
            app.MineTypeDropDownLabel.Text = 'Mine Type';
            app.MineTypeDropDown = uidropdown(app.MineSettingsTab);
            app.MineTypeDropDown.Items = {'Mobile', 'Static'};
            app.MineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MineTypeDropDownValueChanged, true);
            app.MineTypeDropDown.Value = 'Mobile';
            app.ExportButton = uibutton(app.MineSettingsTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ImportButton = uibutton(app.MineSettingsTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.TetherLengthEditFieldLabel = uilabel(app.MineSettingsTab);
            app.TetherLengthEditFieldLabel.Text = 'Tether Length';
            app.TetherLengthEditField = uieditfield(app.MineSettingsTab, 'numeric');
            app.FleetSettingsTab = uitab(app.TabGroup);
            app.FleetSettingsTab.Title = 'Fleet Settings';
            app.FleetBehaviorDropDownLabel = uilabel(app.FleetSettingsTab);
            app.FleetBehaviorDropDownLabel.Text = 'FleetBehavior';
            app.FleetBehaviorDropDown = uidropdown(app.FleetSettingsTab);
            app.FleetBehaviorDropDown.ValueChangedFcn = createCallbackFcn(app, @FleetBehaviorDropDownValueChanged, true);
            app.NumShipsLabel = uilabel(app.FleetSettingsTab);
            app.NumShipsLabel.Text = 'NumShips';
            app.NumShipsEditField = uieditfield(app.FleetSettingsTab, 'numeric');
            app.NumShipsEditField.Limits = [0 10];
            app.NumShipsEditField.ValueChangedFcn = createCallbackFcn(app, @NumShipsEditFieldValueChanged, true);
            app.NumShipsEditField.Value = 1;
            app.DamageRangemEditFieldLabel = uilabel(app.FleetSettingsTab);
            app.DamageRangemEditFieldLabel.Text = 'Damage Range (m)';
            app.DamageRangemEditField = uieditfield(app.FleetSettingsTab, 'numeric');
            app.DamageRangemEditField.ValueChangedFcn = createCallbackFcn(app, @DamageRangemEditFieldValueChanged, true);
            app.DamageRangemEditField.Value = 500;
            app.DetectionRangemEditFieldLabel = uilabel(app.FleetSettingsTab);
            app.DetectionRangemEditFieldLabel.Text = 'Detection Range (m)';
            app.DetectionRangemEditField = uieditfield(app.FleetSettingsTab, 'numeric');
            app.DetectionRangemEditField.ValueChangedFcn = createCallbackFcn(app, @DetectionRangemEditFieldValueChanged, true);
            app.DetectionRangemEditField.Value = 500;
            app.VerificationLogPanel = uipanel(app.UIFigure);
            app.VerificationLogPanel.Title = 'Verification / Log';
            app.VerifyButton = uibutton(app.VerificationLogPanel, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.TextArea = uitextarea(app.VerificationLogPanel);
            app.CompassButton = uibutton(app.UIFigure, 'push');
            app.CompassButton.ButtonPushedFcn = createCallbackFcn(app, @CompassButtonPushed, true);
            app.CompassButton.Text = 'Compass';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TimeLimitsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeLimitsEditFieldLabel.Text = 'Time Limit (s)';
            app.TimeLimitsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeLimitsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLimitsEditFieldValueChanged, true);
            app.TimeLimitsEditField.Value = 30;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = verifyOceanUserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end