classdef verifyParticleEmitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        InitialDirectionSlider       matlab.ui.control.Slider
        InitialDirectionSliderLabel  matlab.ui.control.Label
        ParticleCountSlider          matlab.ui.control.Slider
        ParticleCountSliderLabel     matlab.ui.control.Label
        InitialSpeedSlider           matlab.ui.control.Slider
        InitialSpeedSliderLabel      matlab.ui.control.Label
        TriggerDemoButton            matlab.ui.control.Button
        VerifyButton                 matlab.ui.control.Button
        LogTextArea                  matlab.ui.control.TextArea
        LogTextAreaLabel             matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        emitter % Store the SEParticleEmitter object
        env     % Store the SEEnvironment object
    end
    
    methods (Access = public)
 
        % --- PUBLIC WRAPPER METHODS FOR HEADLESS TESTING ---
        
        function setParticleCount(app, val)
            app.ParticleCountSlider.Value = val;
        end
        
        function setInitialSpeed(app, val)
            app.InitialSpeedSlider.Value = val;
        end
        
        function setInitialDirection(app, val)
            app.InitialDirectionSlider.Value = val;
        end
        
        function triggerDemo(app)
            % Programmatically fires the trigger button callback
            app.TriggerDemoButtonPushed([]);
        end
        
        function [pass, details] = runVerification(app)
            % Executes the verification and returns the results to the script
            testEmitter = SEParticleEmitter(app.UIAxes, 50);
            testEmitter.environment = app.env;
            
            [pass, details] = testEmitter.verify();
            
            delete(testEmitter);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Link the Logger
            logger = Logger.getInstance();
            logger.setTextHandle(app.LogTextArea);
            
            % Setup 3D Axes
            view(app.UIAxes, 3);
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');
            
            % --- EXPANDED TABLE LIMITS ---
            app.UIAxes.XLim = [0 50]; 
            app.UIAxes.YLim = [0 50]; 
            app.UIAxes.ZLim = [-2 10]; % Increased Z height to see the 3D spray
            
            % --- DEFENSIVE ENVIRONMENT FALLBACK ---
            % Try to instantiate the other team's class
            try
                app.env = SEEnvironment();
                force = app.env.globalForce;
            catch
                % If it crashes or doesn't exist yet, catch the error!
                app.env = []; % Leaving this empty tells the emitter to use its internal fallback
                force = [1, 1, 0]; % Hardcoded default force for the visual quiver
                
                % Log a warning so you know it's using the fallback
                logger.log('WARNING', 'SEEnvironment not found or failed. Using fallback environment force.');
            end
            
            % --- UPDATED QUIVER POSITION ---
            % Moved the arrow to the new corner (45, 45) and scaled it
            quiver3(app.UIAxes, 45, 45, 0, force(1)*5, force(2)*5, force(3)*5, ...
                'Color', '#0072BD', 'LineWidth', 2, 'MaxHeadSize', 2);
            text(app.UIAxes, 45, 45, 1, 'Env Force', 'Color', '#0072BD', 'FontWeight', 'bold');
            
            % FIXED LOGGER COMMAND:
            logger.log('STATUS', '3D Emitter Tool Ready (Expanded 50x50 Grid).');
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            % Create a temp emitter the test
            testEmitter = SEParticleEmitter(app.UIAxes, 50);
            testEmitter.environment = app.env;
            
            % Execute the verification contract (results appear in LogTextArea)
            testEmitter.verify();
            
            % Cleanup
            delete(testEmitter);            
        end

        % Button pushed function: TriggerDemoButton
        function TriggerDemoButtonPushed(app, event)
            % Get the logger instance
            logger = Logger.getInstance();
            
            if ~isempty(app.emitter); delete(app.emitter); end
            
            % 1. Read values from all three sliders
            numParts = round(double(app.ParticleCountSlider.Value));
            speed = app.InitialSpeedSlider.Value; 
            directionDeg = app.InitialDirectionSlider.Value; % <-- NEW: Read direction

            % Calculate X and Y velocity components using trigonometry
            vx = speed * cosd(directionDeg);
            vy = speed * sind(directionDeg);
            initVel = [vx, vy, 0]; % Compile into a 3D vector
            
            logger.log('INFO', sprintf('Starting demo: %d particles, Speed: %.1f, Dir: %.1f deg', numParts, speed, directionDeg));
            
            % 2. Instantiate and Trigger
            app.emitter = SEParticleEmitter(app.UIAxes, numParts);
            app.emitter.environment = app.env;
    
            % Trigger using the newly calculated velocity vector
            app.emitter.trigger([25, 25, 0], initVel);
            
            % 3. Animation Loop
            dt = 0.1;
            while isvalid(app.emitter) && app.emitter.is_active
                app.emitter.update(dt);
                drawnow limitrate;
                pause(0.05); 
            end
            
            % 4. LOG THE COMPLETION
            logger.log('STATUS', 'Simulation complete. All particles expired.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '3D Particle Detonation Demo')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LogTextAreaLabel = uilabel(app.Panel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.Panel);
            app.VerifyButton = uibutton(app.Panel, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.TriggerDemoButton = uibutton(app.Panel, 'push');
            app.TriggerDemoButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerDemoButtonPushed, true);
            app.TriggerDemoButton.Text = 'Trigger Demo';
            app.InitialSpeedSliderLabel = uilabel(app.Panel);
            app.InitialSpeedSliderLabel.Text = 'Initial Speed';
            app.InitialSpeedSlider = uislider(app.Panel);
            app.InitialSpeedSlider.Limits = [0 20];
            app.ParticleCountSliderLabel = uilabel(app.Panel);
            app.ParticleCountSliderLabel.Text = 'Particle Count';
            app.ParticleCountSlider = uislider(app.Panel);
            app.ParticleCountSlider.Limits = [10 200];
            app.ParticleCountSlider.Step = 1;
            app.ParticleCountSlider.Value = 10;
            app.InitialDirectionSliderLabel = uilabel(app.Panel);
            app.InitialDirectionSliderLabel.Text = 'Initial Direction';
            app.InitialDirectionSlider = uislider(app.Panel);
            app.InitialDirectionSlider.Limits = [0 360];
            app.InitialDirectionSlider.MajorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = verifyParticleEmitter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end