classdef mobilemineSim_sp_2025_TacticalSideView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        SideViewAxes                   matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        ResetButton                    matlab.ui.control.Button
        VerifyButton                   matlab.ui.control.Button
        ViewChangeButton               matlab.ui.control.Button
        StatusLabel                    matlab.ui.control.Label
        AnimateCheckBox                matlab.ui.control.CheckBox
        StartButton                    matlab.ui.control.Button
        TimeLimitsEditField            matlab.ui.control.NumericEditField
        TimeLimitsEditFieldLabel       matlab.ui.control.Label
        NumRunsEdit                    matlab.ui.control.NumericEditField
        RunsLabel                      matlab.ui.control.Label
        FleetTab                       matlab.ui.container.Tab
        DisplayShipHeadingCheckBox     matlab.ui.control.CheckBox
        FleetBehaviorMenu              matlab.ui.control.DropDown
        BehaviorDropDown_2Label        matlab.ui.control.Label
        NumShipsEdit                   matlab.ui.control.NumericEditField
        ShipsLabel                     matlab.ui.control.Label
        MinefieldTab                   matlab.ui.container.Tab
        MineTypeDropDown               matlab.ui.control.DropDown
        MineTypeDropDownLabel          matlab.ui.control.Label
        DetectionRangemEditField       matlab.ui.control.NumericEditField
        DetectionRangemEditFieldLabel  matlab.ui.control.Label
        LayoutDropDown                 matlab.ui.control.DropDown
        LayoutDropDownLabel            matlab.ui.control.Label
        DamageRangemEditField          matlab.ui.control.NumericEditField
        DamageRangemEditFieldLabel     matlab.ui.control.Label
        MineCountEditField             matlab.ui.control.NumericEditField
        MineCountEditFieldLabel        matlab.ui.control.Label
        HTML                           matlab.ui.control.HTML
        ContextMenu                    matlab.ui.container.ContextMenu
        UnlockMenu                     matlab.ui.container.Menu
        RRMenu                         matlab.ui.container.Menu
    end

    % Team Sea Shield: - 
    % Added renderer3D, boundary_box, minefield_box, and localMaxSteps as private properties.
    % Added rebuildSimulation() to recreate the engine, reapply UI settings, reattach listeners, apply mine ranges, and reset both displays.
    % Added resetDisplays() to reset the overhead map, reset the side-view renderer, and refresh both views.
    % Added applyMineRanges() so damage and detection range fields update mine settings.
    % Updated refreshDisplay() so it refreshes both the overhead map and the side view.
    % Updated simUpdatedCb() so the side view updates continuously during simulation.
    % Updated StartButtonPushedCb() to rebuild the simulation before running.
    % Added callbacks for damage and detection range edits so those controls immediately refresh the simulation view.

    properties (Access = private)
        renderer3D
        boundary_box (1,4) double = [0, 0.1, 6, 8.9]
        minefield_box (1,4) double = [1.5, 1.5, 3.0, 6.0]
        localMaxSteps (1,1) double = 50
    end

    properties (SetAccess = public)
        simEngine % SESimulatorEngine
    end
    
    methods (Access = public)
        function setHtml(app, htmlContent)
            if nargin < 2 || isempty(htmlContent)
                htmlContent = '';
            end
            htmlStr = sprintf('<html><body>%s</body></html>', htmlContent);
            app.HTML.HTMLSource = htmlStr;
        end

        function setStatus(app, statusStr)
            app.StatusLabel.Text = statusStr;
        end

        function setMineType(app, mineType)
            app.simEngine.setMineType(mineType);
        end

        function numMines = getNumMines(app)
            numMines = app.MineCountEditField.Value;
        end

        function numShips = getNumShips(app)
            numShips = app.NumShipsEdit.Value;
        end

        function setNumShips(app, num_ships)            
            app.simEngine.setNumShips(num_ships);
            app.refreshDisplay();
        end

        function setNumMines(app, num_mines)
            app.simEngine.setNumMines(num_mines);
            app.refreshDisplay();
        end

        function didSet = setMinefieldLayout(app, layout)
            didSet = app.simEngine.setMinefieldLayout(layout);
            app.refreshDisplay();
        end

        function setFleetBehavior(app, behavior)
            app.simEngine.setFleetBehavior(behavior);
            app.refreshDisplay();
        end

        function refreshDisplay(app)
            % Updated to refresh both 2d and side view display.
            % Sideview must be updated every time the sim display refreshes.
            if isempty(app.simEngine)
                return;
            end

            app.simEngine.refreshDisplay();

            if ~isempty(app.renderer3D) && isvalid(app.renderer3D)
                app.renderer3D.update(app.simEngine);
            end
        end

        function disableWidgets(app)
            app.setEnableWidgets(false);
            app.AnimateCheckBox.Enable="on";
            app.StatusLabel.Enable = "on";  % want to keep the status easy to read.
            % app.StartButton.Enable = "on";
            app.ViewChangeButton.Enable = "on";
            app.ResetButton.Enable = "on";
        end

        function enableWidgets(app)
            app.setEnableWidgets(true);
        end

        function setTitle(app, titleStr, varargin)
            titleStr = sprintf(titleStr, varargin{:});
            app.UIFigure.Name = titleStr;
        end

        function setEnableWidgets(app, enabled)
            tabs = app.TabGroup.Children;
            if enabled
                enabledTag = 'on';
            else
                enabledTag = 'off';
            end
            for t=1:numel(tabs)
                tab = tabs(t);
                childrenH = tab.Children;
                for c=1:numel(childrenH)
                    h = childrenH(c);
                    if isprop(h,'enable')
                        set(h,'enable',enabledTag);
                    end
                end
            end
        end


        function updateStatistics(app)
            stats = app.simEngine.getStatistics();

            % Generate the HTML string
            htmlStr = sprintf(['<html>'...
                '<head><style>'...
                'table {font-family: Arial, sans-serif; border-collapse: collapse; width: 100%%;}'...
                'th, td {border: 1px solid #dddddd; text-align: left; padding: 8px;}'...
                'th {background-color: #f2f2f2;}'...
                '</style></head>'...
                '<body>'...
                '<h2>Statistics</h2>'...
                '<table>'...
                '<tr><th>Statistic</th><th>Value</th></tr>'...
                '<tr><td>Ships Remaining</td><td>%d</td></tr>'...
                '<tr><td>Ships Sunk</td><td>%d</td></tr>'...
                '<tr><td>Proportion of Ships Remaining</td><td>%.2f</td></tr>'...
                '<tr><td>Mines Remaining</td><td>%d</td></tr>'...                
                '<tr><td>Mines Detonated</td><td>%d</td></tr>'...
                '<tr><td>Proportion of Mines Remaining</td><td>%.2f</td></tr>'...
                '</table>'...
                '</body>'...
                '</html>'], stats.shipsRemaining, stats.shipsSunk, stats.proportionShipsRemaining, stats.minesRemaining, stats.minesDetonated, stats.proportionMinesRemaining);


            app.setHtml(htmlStr);
            % Generate the HTML string
            htmlString = sprintf(['<html>'...
                '<head><style>'...
                'table {font-size: 12px; font-family: Arial, sans-serif; border-collapse: collapse; width: 100%%;}'...
                'th, td {border: 1px solid #dddddd; text-align: right; padding: 8px;}'...
                'th {background-color: #f2f2f2;}'...
                'h2, h3,h4, h5 {font-family: Arial, sans-serif;}'...
                '</style></head>'...
                '<body>'...
                '<table>'...
                '<tr><th></th><th>Ships</th><th>Mines</th></tr>'...
                '<tr><td><b>Remaining</b></td><td>%d</td><td>%d</td></tr>'...
                '<tr><td><b>Destroyed</b></td><td>%d</td><td>%d</td></tr>'...
                '<tr><td><b>Percent Remaining</b></td><td>%.1f%%</td><td>%.1f%%</td></tr>'...
                '</table>'...
                '<h5>Run %d</h5>'...
                '</body>'...
                '</html>'], ...
                stats.shipsRemaining, stats.minesRemaining, ...
                stats.shipsSunk, stats.minesDetonated, ...
                stats.proportionShipsRemaining*100, stats.proportionMinesRemaining*100,...
                app.simEngine.curSimulation);

            app.setHtml(htmlString);
        end
    
        function saveStatistics(app)
            mineType = app.MineTypeDropDown.Value;
            numMines = app.MineCountEditField.Value;
            mineLayout = app.LayoutDropDown.Value;
            fleetBehavior = app.FleetBehaviorMenu.Value;

            filename = sprintf('results_%s_%d_%s_%s.csv', mineType, numMines, mineLayout, fleetBehavior);
            
            if exist(filename, 'file')
                fid = fopen (filename, 'a');
            else
                fid= fopen (filename, 'w');
                headerLine = sprintf('shipsRemaining, shipsSunk, proportionShipsRemaining, minesRemaining, minesDetonated, proportionMinesRemaining');
                fprintf(fid, '%s\n', headerLine); 
                %fprintf(fid, headerLine);
            end
            stats = app.simEngine.getStatistics();     
            fprintf(fid, '%d, %d,%.2f,%d,%d,%.2f\n', ...
                stats.shipsRemaining, stats.shipsSunk, stats.proportionShipsRemaining, stats.minesRemaining, stats.minesDetonated, stats.proportionMinesRemaining);
            fclose(fid);

        end
    end


    methods (Access = private)
        
        function simUpdatedCb(app, simEngineH, evt)
            % Statistics and both displays update continuously while
            % running.
            app.updateStatistics();
            app.refreshDisplay();
            drawnow limitrate;
        end
        function simCompletedCb(app, simEngineH, evt)
            app.setStatus(sprintf('Run: %d', app.simEngine.curSimulation+1));
            drawnow();
            app.saveStatistics();
        end
        function runCompletedCb(app, simEngineH, evt)
            app.updateStatistics();
        end
        
        % Added applyMineRanges as detection range fields were not being 
        % pushed into mine objects
        function applyMineRanges(app)
            if isempty(app.simEngine) || isempty(app.simEngine.minefield)
                return;
            end

            mines = app.simEngine.minefield.mines;
            damageRange = app.DamageRangemEditField.Value;
            detectRange = app.DetectionRangemEditField.Value;

            for k = 1:numel(mines)
                try
                    mines(k).damageRange = damageRange;
                    mines(k).detectRange = detectRange;
                    %mines(k).updateDisplay();
                catch
                end
            end
        end

        function updateViewButtonLabel(app)
            if isempty(app.renderer3D) || ~isvalid(app.renderer3D)
            app.ViewChangeButton.Text = 'View Change';
            return;
            end

            try
            app.ViewChangeButton.Text = ['View: ' char(app.renderer3D.getViewName())];
            catch
            app.ViewChangeButton.Text = 'View Change';
            end
        end
        % Added for test method and verification, remove if needed.
        function rebuildSimulation(app)
            app.localMaxSteps = max(1, floor(app.TimeLimitsEditField.Value));

            if ~isempty(app.simEngine)
                try
                    if isvalid(app.simEngine)
                        delete(app.simEngine);
                    end
                catch
                end
            end

            if isgraphics(app.UIAxes)
                cla(app.UIAxes, 'reset');
            end

            app.simEngine = SESimulatorEngine();

            app.simEngine.addlistener('SimUpdated', @app.simUpdatedCb);
            addlistener(app.simEngine, 'SimCompleted', @app.simCompletedCb);
            addlistener(app.simEngine, 'MonteCarloFinished', @app.runCompletedCb);

            app.simEngine.setBoundaryBox(app.boundary_box);
            app.simEngine.setMinefieldBox(app.minefield_box);
            app.simEngine.setAnimate(app.AnimateCheckBox.Value);
            app.simEngine.setNumRuns(app.NumRunsEdit.Value);
            app.simEngine.setFleetBehavior(app.FleetBehaviorMenu.Value);
            app.simEngine.setMinefieldLayout(app.LayoutDropDown.Value);
            app.simEngine.setMineType(lower(app.MineTypeDropDown.Value));
            app.simEngine.setAxesHandle(app.UIAxes);
            app.simEngine.setNumShips(app.NumShipsEdit.Value);
            app.simEngine.setNumMines(app.MineCountEditField.Value);
            app.simEngine.displayShipHeadings(app.DisplayShipHeadingCheckBox.Value);

            app.applyMineRanges();
            app.resetDisplays();
        end

        % Reconfigures the overhead UIAxes appearance. 
        % Calls app.simEngine.refreshDisplay().
        function resetDisplays(app)
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            xlim(app.UIAxes, [0 6]);
            ylim(app.UIAxes, [0 9]);
            set(app.UIAxes, ...
                'Color', [0.6745 0.9686 0.9882], ...
                'Box', 'on', ...
                'XGrid', 'on', ...
                'YGrid', 'on');
            title(app.UIAxes, 'Overhead Map Perspective');

            app.simEngine.refreshDisplay();

            if ~isempty(app.renderer3D) && isvalid(app.renderer3D)
                app.renderer3D.reset();
                app.renderer3D.update(app.simEngine);
            end

            app.updateStatistics();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initializes and sets: 
            % - simengine
            % - listeners
            % - UI values
            % - boundary and minefield boxes
            % - side renderer
            % - rebuildSimulation
            app.setTitle('NPS Mine Simulator');

            app.UIAxes.Visible = 'on';
            app.SideViewAxes.Visible = 'on';

            app.NumRunsEdit.Value = 1;

            % Create temporary engine to populate dropdowns if needed
            tmpEngine = SESimulatorEngine();

            fleet_behaviors = tmpEngine.getValidFleetBehaviors();
            minefield_layouts = tmpEngine.getValidMinefieldLayouts();

            app.FleetBehaviorMenu.Items = fleet_behaviors;
            app.LayoutDropDown.Items = minefield_layouts;

            try
                delete(tmpEngine);
            catch
            end
            
            % SideView initialization and preset 
            app.renderer3D = SESideViewTacticalStatus(app.SideViewAxes, app.boundary_box, app.minefield_box);
            app.rebuildSimulation();
            app.renderer3D.applyViewPreset();

            app.setHtml('');
            app.setStatus('Ready');
        end

        % Value changed function: NumRunsEdit
        function NumRunsEditCb(app, event)
            value = app.NumRunsEdit.Value;
            app.simEngine.setNumRuns(value);
        end

        % Value changed function: TimeLimitsEditField
        function TimeLimitsEditFieldCb(app, event)
            value = app.TimeLimitsEditField.Value;
            app.simEngine.setTimeLimit(value);
        end

        % Button pushed function: StartButton
        function StartButtonPushedCb(app, event)
            %PENDING TO ADD THIS FUNCTION TO RUN THE SIMULATION
            app.disableWidgets();            
            app.setStatus('Run: 1');
            drawnow();

            % We want to rebuild from the current UI settings before each run.
            app.rebuildSimulation();
            app.simEngine.run(app.NumRunsEdit.Value);

            app.updateStatistics();  
            app.setStatus('Ready');
            app.enableWidgets();
        end

        % Value changed function: NumShipsEdit
        function NumShipsEditValueChanged(app, event)
            value = app.NumShipsEdit.Value;
            app.setNumShips(value);
            % fprintf(1,'Number of ships changed to: %d\n', value);
        end

        % Value changed function: FleetBehaviorMenu
        function FleetBehaviorMenuValueChanged(app, event)
            value = app.FleetBehaviorMenu.Value;
            app.setFleetBehavior(value);
            % fprintf(1,'Fleet behavior is now: %s\n', value);
        end

        % Value changed function: MineCountEditField
        function MineCountEditFieldValueChanged(app, event)
            value = app.MineCountEditField.Value;
            app.setNumMines(value);
        end

        % Value changed function: LayoutDropDown
        function LayoutDropDownCb(app, event)
            value = app.LayoutDropDown.Value;
            app.setMinefieldLayout(value);            
        end

        % Menu selected function: UnlockMenu
        function UnlockMenuSelected(app, event)
            app.enableWidgets();
        end

        % Menu selected function: RRMenu
        function RRMenuSelected(app, event)
            web('https://youtu.be/dQw4w9WgXcQ?si=P4Xfbnwc87U4JXh8');
        end

        % Value changed function: DisplayShipHeadingCheckBox
        function DisplayShipHeadingCheckBoxValueChanged(app, event)
            value = app.DisplayShipHeadingCheckBox.Value;
            app.simEngine.displayShipHeadings(value);
        end

        % Value changed function: MineTypeDropDown
        function MineTypeDropDownValueChanged(app, event)
            value = app.MineTypeDropDown.Value;
            app.setMineType(value);
        end

        % Value changed function: AnimateCheckBox
        function AnimateCheckBoxValueChanged(app, event)
            value = app.AnimateCheckBox.Value;
            app.simEngine.setAnimate(value);
        end

        % Button pushed function: ViewChangeButton
        function ViewChangeButtonPushed(app, event)
           if isempty(app.renderer3D) || ~isvalid(app.renderer3D)
           return;
           end
           app.renderer3D.nextView();
           app.updateViewButtonLabel();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.rebuildSimulation();
            app.setStatus('Reset complete');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.HTML = uihtml(app.GridLayout2);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.RunsLabel = uilabel(app.SimulationTab);
            app.RunsLabel.Text = 'Number of Runs';
            app.NumRunsEdit = uieditfield(app.SimulationTab, 'numeric');
            app.NumRunsEdit.ValueChangedFcn = createCallbackFcn(app, @NumRunsEditCb, true);
            app.NumRunsEdit.Value = 1;
            app.TimeLimitsEditFieldLabel = uilabel(app.SimulationTab);
            app.TimeLimitsEditFieldLabel.Text = 'Time Limit (s)';
            app.TimeLimitsEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TimeLimitsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLimitsEditFieldCb, true);
            app.TimeLimitsEditField.Value = 30;
            app.StartButton = uibutton(app.SimulationTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushedCb, true);
            app.StartButton.Text = 'Start';
            app.AnimateCheckBox = uicheckbox(app.SimulationTab);
            app.AnimateCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnimateCheckBoxValueChanged, true);
            app.AnimateCheckBox.Text = 'Animate';
            app.AnimateCheckBox.Value = true;
            app.StatusLabel = uilabel(app.SimulationTab);
            app.StatusLabel.Text = 'Status';
            app.ViewChangeButton = uibutton(app.SimulationTab, 'push');
            app.ViewChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ViewChangeButtonPushed, true);
            app.ViewChangeButton.Text = 'View Change';
            app.VerifyButton = uibutton(app.SimulationTab, 'push');
            app.VerifyButton.Text = 'Verify';
            app.ResetButton = uibutton(app.SimulationTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FleetTab = uitab(app.TabGroup);
            app.FleetTab.Title = 'Fleet';
            app.ShipsLabel = uilabel(app.FleetTab);
            app.ShipsLabel.Text = 'Ships';
            app.NumShipsEdit = uieditfield(app.FleetTab, 'numeric');
            app.NumShipsEdit.ValueChangedFcn = createCallbackFcn(app, @NumShipsEditValueChanged, true);
            app.NumShipsEdit.Value = 10;
            app.BehaviorDropDown_2Label = uilabel(app.FleetTab);
            app.BehaviorDropDown_2Label.Text = 'Behavior';
            app.FleetBehaviorMenu = uidropdown(app.FleetTab);
            app.FleetBehaviorMenu.Items = {'Kamikaze', 'Random', 'Channelized'};
            app.FleetBehaviorMenu.ValueChangedFcn = createCallbackFcn(app, @FleetBehaviorMenuValueChanged, true);
            app.FleetBehaviorMenu.Value = 'Kamikaze';
            app.DisplayShipHeadingCheckBox = uicheckbox(app.FleetTab);
            app.DisplayShipHeadingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayShipHeadingCheckBoxValueChanged, true);
            app.DisplayShipHeadingCheckBox.Text = 'Display Ship Heading';
            app.DisplayShipHeadingCheckBox.Value = true;
            app.MinefieldTab = uitab(app.TabGroup);
            app.MinefieldTab.Title = 'Minefield';
            app.MineCountEditFieldLabel = uilabel(app.MinefieldTab);
            app.MineCountEditFieldLabel.Text = 'Mine Count';
            app.MineCountEditField = uieditfield(app.MinefieldTab, 'numeric');
            app.MineCountEditField.ValueChangedFcn = createCallbackFcn(app, @MineCountEditFieldValueChanged, true);
            app.MineCountEditField.Value = 10;
            app.DamageRangemEditFieldLabel = uilabel(app.MinefieldTab);
            app.DamageRangemEditFieldLabel.Text = 'Damage Range (m)';
            app.DamageRangemEditField = uieditfield(app.MinefieldTab, 'numeric');
            app.DamageRangemEditField.Value = 500;
            app.LayoutDropDownLabel = uilabel(app.MinefieldTab);
            app.LayoutDropDownLabel.Text = 'Layout';
            app.LayoutDropDown = uidropdown(app.MinefieldTab);
            app.LayoutDropDown.Items = {'Random', 'Smart', 'Dumb'};
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @LayoutDropDownCb, true);
            app.LayoutDropDown.Value = 'Smart';
            app.DetectionRangemEditFieldLabel = uilabel(app.MinefieldTab);
            app.DetectionRangemEditFieldLabel.Text = 'Detection Range (m)';
            app.DetectionRangemEditField = uieditfield(app.MinefieldTab, 'numeric');
            app.DetectionRangemEditField.Value = 500;
            app.MineTypeDropDownLabel = uilabel(app.MinefieldTab);
            app.MineTypeDropDownLabel.Text = 'Mine Type';
            app.MineTypeDropDown = uidropdown(app.MinefieldTab);
            app.MineTypeDropDown.Items = {'Mobile', 'Static'};
            app.MineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MineTypeDropDownValueChanged, true);
            app.MineTypeDropDown.Value = 'Mobile';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout3);
            app.UIAxes.XLim = [0 6];
            app.UIAxes.YLim = [0 9];
            app.UIAxes.XTick = [0 1 2 3 4 5 6];
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7 8 9];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'};
            app.UIAxes.Visible = 'off';
            app.SideViewAxes = uiaxes(app.GridLayout3);
            title(app.SideViewAxes, 'Tactical View Display')
            app.SideViewAxes.XTick = [];
            app.SideViewAxes.YTick = [];
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.UnlockMenu = uimenu(app.ContextMenu);
            app.UnlockMenu.MenuSelectedFcn = createCallbackFcn(app, @UnlockMenuSelected, true);
            app.UnlockMenu.Text = 'Unlock';
            app.RRMenu = uimenu(app.ContextMenu);
            app.RRMenu.MenuSelectedFcn = createCallbackFcn(app, @RRMenuSelected, true);
            app.RRMenu.Text = 'RR';
            % Assign app.ContextMenu
            app.GridLayout2.ContextMenu = app.ContextMenu;
            app.TabGroup.ContextMenu = app.ContextMenu;
            app.SimulationTab.ContextMenu = app.ContextMenu;
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mobilemineSim_sp_2025_TacticalSideView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end