classdef appKaiya < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BoomLabel                 matlab.ui.control.Label
        shipittotheminsteadLabel  matlab.ui.control.Label
        DontdropthebombLabel      matlab.ui.control.Label
        SurpriseButton            matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        axe                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        mine % Description
        ship
    end
    
    methods (Access = private)


        function c = detection(app)
            mx = app.mine.pos_x;
            my = app.mine.pos_y;
            sx = app.ship.pos_x;
            sy = app.ship.pos_y;
            
            a = abs(mx-sx);
            b = abs(my-sy);

            a = power(a, 2);
            b = power(b, 2);

            c = sqrt(a+b);


        end


    end
    
    methods (Access = public)
        
        % function numShips = getNumShips(app)
        %     numShips = app.NumShipsEdit;
        % end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mine= SEMobileMine(0,0,app.axe);
            app.mine.damageRange = 90;
            app.ship= SEShip(0,500,app.axe);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            

            %testing getDistance function (SEMine class)
            g = app.mine.getDistance(0,100);
            disp(g);
    
            % clc, clearvars, close all; - this clears, everything and we
            % don't want in a function, only - at times - in scripts

            fprintf('I was pushed. \n');

            
            a= app.axe;
          
            %creating graph
            a.XLim=[0 1000];
            a.YLim=[0 1000];
            a.XGrid='off';
            a.YGrid='off';
            a.XTickLabel=[];
            a.YTickLabel=[];
           

            %animation
            dx=0.5; %direction
            dy = 0.5;
            sx= 0.5;
            sy=0.5;
            fps=150;

            %animation
            for k=1:1100
                
                if app.detection() <= app.mine.damageRange

                    fprintf('EXPLOSION!!!!!');
                    app.BoomLabel.FontColor = [1, 0.3, 0];
                   
                    break;
                end


                if app.mine.pos_y > app.axe.YLim(2)
                    dy = -dy;
                else
                    if app.mine.pos_y < app.axe.YLim(1)
                        dy = -dy;
                    end
                end
                %ship animation
                % if ship.pos_x > app.axe.XLim(2)
                %     sx = -0.5;
                % elseif ship.pos_x < app.axe.XLim(1)
                %     sx = +0.5;
                % end
                %
                % if ship.pos_y > app.axe.YLim(2)
                %     sy = -0.5;
                % elseif ship.pos_x < app.axe.YLim(1)
                %     sy = +0.5;
                % end


                if app.ship.pos_x > app.axe.XLim(2)
                    dx = -dx;
                else
                    if app.ship.pos_x < app.axe.XLim(1)

                        dx = -dx;
                    end
                end

                %move markers
                % l.XData = l.XData + dx;
                pause(1/fps);

                %move mine
                app.mine.pos_x= app.mine.pos_x+dy;
                app.mine.pos_y= app.mine.pos_y+dy;
                app.mine.updateDisplay;
              
                
                %move ship
                app.ship.pos_x= app.ship.pos_x+dx;
                
                
                app.ship.updateDisplay;
            end


        %move mine


        end

        % Button pushed function: SurpriseButton
        function SurpriseButtonPushed(app, event)
            for k=1:5
                app.SurpriseButton.BackgroundColor= 'red';
                pause(0.5);
                app.SurpriseButton.BackgroundColor= 'blue';
                pause(0.5);
                app.SurpriseButton.BackgroundColor= 'green';
                pause(0.5);
                app.SurpriseButton.BackgroundColor= 'magenta';
                pause(0.5);
                app.SurpriseButton.BackgroundColor= 'green';
                pause(0.5);
                app.SurpriseButton.BackgroundColor= 'cyan';
                pause(0.5);
                app.SurpriseButton.BackgroundColor= 'white';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axe = uiaxes(app.UIFigure);
            title(app.axe, 'Title')
            zlabel(app.axe, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SurpriseButton = uibutton(app.UIFigure, 'push');
            app.SurpriseButton.ButtonPushedFcn = createCallbackFcn(app, @SurpriseButtonPushed, true);
            app.SurpriseButton.Text = 'Surprise!';
            app.DontdropthebombLabel = uilabel(app.UIFigure);
            app.DontdropthebombLabel.Text = 'Don''t drop the bomb....';
            app.shipittotheminsteadLabel = uilabel(app.UIFigure);
            app.shipittotheminsteadLabel.Text = 'ship it to them instead!';
            app.BoomLabel = uilabel(app.UIFigure);
            app.BoomLabel.Text = 'Boom!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appKaiya

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end