classdef app_hyatt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DemoHyattUIFigure  matlab.ui.Figure
        RunButton          matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            fprintf('I was clicked.\n');


            mine = SEMine(0, 0, app.UIAxes);           
            

            y = (rand(1,4)-0.5)*10;

            x = (rand(1,4)-0.5)*10;

            dir_x = sign(x);
            dir_y = sign(y);
            speed = 0.1;

            markerType = 'o';
            markerColor = [0.8, 0.5, 0.5];
            markerEdgeColor = 'black';

            a= app.UIAxes;
            a.NextPlot = 'add';

            l = plot(app.UIAxes,x,y);    % line handle
            a = get(l,'parent');    % axes handle
            
            % Create a boundary to fix things to do
            limits = [-10, 10];
            a.XLim = limits;
            a.YLim = limits;
            % a.Box = "on";
            % a.XGrid = "on";
            % a.YGrid = "on";
            % a.XTickLabel = '';
            % a.YTickLabel = [];  % this works too

            l.LineStyle = 'none';       % no lines
            l.Marker = markerType;
            l.MarkerEdgeColor = markerEdgeColor;
            l.MarkerFaceColor = markerColor;
            l.MarkerSize = 15;

            fps = 20;       % frame rate
            dur_sec = 10;
            num_frames = fps*dur_sec;
            for frame = 1:num_frames

                % boundary checks - this uses vectorization as a shortcut to if/else
                % statements, but the logic is the same.
                tooFarRight = l.XData > limits(end);
                tooFarLeft = l.XData < limits(1);
                tooLow = l.YData < limits(1);
                tooHigh = l.YData > limits(end);

                title_str = sprintf('Demo (% 3d)', frame);
                f.Name = title_str;
                dir_x(tooFarLeft) = 1;
                dir_x(tooFarRight) = -1;
                dir_y(tooLow) = 1;
                dir_y(tooHigh) = -1;

                % update position
                l.XData = l.XData + dir_x*speed;
                l.YData = l.YData + dir_y*speed;

                mine.update();
                pause(1/fps);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DemoHyattUIFigure = uifigure('Visible', 'off');
            app.DemoHyattUIFigure.Name = 'Demo Hyatt';
            app.UIAxes = uiaxes(app.DemoHyattUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.RunButton = uibutton(app.DemoHyattUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.DemoHyattUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_hyatt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DemoHyattUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DemoHyattUIFigure)
        end
    end
end