classdef plot_app_gabino < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        RunItButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mine % Instance of an SEMine
        ship % Instance of an SEShip
        explosion % Instance of an explosion
    end
    
    methods (Access = private)
        
        function p = detection(app)
            mx = app.mine.pos_x;
            my = app.mine.pos_y;
            sx = app.ship.pos_x;
            sx = app.ship.pos_y;

            xdiff = abs(mx-sx);
            ydiff = abs(my-sy);
            a = power(xdiff, 2);
            b = power(ydiff, 2);
            c = sqrt(a+b);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XLim = [-40 40];
            app.UIAxes.YLim = [-40 40];

            app.mine = SEMine(0,0,app.UIAxes);
            app.ship = SEShip(-40,0,app.UIAxes);

        end

        % Button pushed function: RunItButton
        function RunItButtonPushed(app, event)
            dx = 0.5;
            sdx = 0.5;
            mdx = 0.5;
            mdy = 0.25;
            fps = 30;
            k=0;
            a.Box = 'on';
            
            y = ([-30,-10,10,30]);
            x = ([0,0,0,0]);

            %l = plot(app.UIAxes,x,y);
            %l.Color = 'red';
            %l.LineStyle = 'none';
            %l.LineWidth = 5;
            %l.Marker = 'o';        
        
            for k = 1:1000
                
             %   if l.XData(1) > app.UIAxes.XLim(2)
             %       dx = -dx;
             %   else
             %       if l.XData(1) < app.UIAxes.XLim(1)
             %           dx = -dx;
             %       end
             %   end

             
                if app.mine.pos_y >  app.UIAxes.YLim(2)
                    mdy = -mdy;
                else
                   if app.mine.pos_y < app.UIAxes.YLim(1)
                        mdy = -mdy;
                    end
                end

                if app.mine.pos_x >  app.UIAxes.XLim(2)
                    mdx = -mdx;
                else
                    if app.mine.pos_x < app.UIAxes.XLim(1)
                        mdx = -mdx;
                    end
                end
                

                if app.ship.pos_x >  app.UIAxes.XLim(2)
                    sdx = -sdx;
                else
                    if app.ship.pos_x < app.UIAxes.XLim(1)
                        sdx = -sdx;
                    end
                end

                %if  app.mine.pos_x - 15 < app.ship.pos_x < app.mine.pos_x + 15
                %    fprintf('HIT')
                %end

                %l.XData = l.XData + dx;

                app.ship.pos_x = app.ship.pos_x + sdx;
                app.ship.updateDisplay;

                app.mine.pos_x = app.mine.pos_x + mdx;
                %app.mine.pos_y = app.mine.pos_y + mdy;
                app.mine.updateDisplay;
                pause(1/fps);

                %if app.detection() <= app.mine.damageRange
                %    fprintf('hit');
                %end
    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RunItButton = uibutton(app.UIFigure, 'push');
            app.RunItButton.ButtonPushedFcn = createCallbackFcn(app, @RunItButtonPushed, true);
            app.RunItButton.Text = 'Run It!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_app_gabino

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end