classdef sabrinasapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StartButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mine % SEMine
        ship % Description
    end

    
    methods (Access = private)
        
        function c = detection(app)
            mx = app.mine.pos_x;
            my = app.mine.pos_y;
            sx = app.ship.pos_x;
            sy = app.ship.pos_y;
            
            if mx == sx
                c = abs(my-sy);
            elseif my == sy
                c = abs(mx-sx);
            else
                %c = sqrt( power(abs(sx-mx),2) + power(abs(sy-mx);2) ); 


                a = abs(mx-sx);
                    b = abs(my-sy);
                    a = power(a, 2);
                    b = power(b, 2);
                    d = a+b;
                    c = sqrt(d);
                    fprintf(c);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mine = SEMine(0,0,app.UIAxes);
            app.ship = SEShip(0,600, app.UIAxes);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            %y = ([3,-15,9,20]);
            %x = ([5,6,7,8]);
            
            a= app.UIAxes;

            
            %l = plot(a,x,y);
            dx = 3;
            dy = 3;

%pos_x = 0;
%pos_y = 6;
%axes_h = app.UIAxes;
%set(axes_h, 'xlim',[0 10], 'ylim', [0 10]);

            
            %l.Marker = 'pentagram';
            %l.MarkerSize=20;
            %l.MarkerFaceColor = '#E274B3';
            %l.MarkerEdgeColor = '#89245E';
            %l.LineStyle = "none";
            %app.UIFigure.Name = 'Sabrina';
            a.XLim = [0 1000];
            a.YLim = [0 1000];
            fps=100;
           
            for k=  1:11000
                %marker animation
                %if l.XData(1) > a.XLim(2)
                    %dx = -0.5;
                %else
                    %if l.XData(2) < a.XLim(1)
                        %dx = +0.5;
                    %end
                %end
                
                if app.detection() <= app.mine.damageRange
                    fprintf('hit');
                    %fprintf(app.detection());
                    break;
                end
                %mine animation
                if app.mine.pos_y > app.UIAxes.YLim(2)
                    dy = -dy;
                else 
                    if app.mine.pos_y < app.UIAxes.YLim(1)
                        dy = -dy;
                    end
               end

                %ship animation
                if app.ship.pos_x > app.UIAxes.XLim(2)
                    dx = -dx;
                else 
                    if app.ship.pos_x < app.UIAxes.XLim(1)
                        dx = -dx;
                    end
                end
                
                
                pause(1/fps);
                app.ship.pos_x = app.ship.pos_x + dx;  %moves back and fourth
                %ship.pos_y = ship.pos_y + dy;  moves up and down
                app.ship.updateDisplay;

                %l.XData = l.XData + dx;
                
                app.mine.pos_x = app.mine.pos_x + dy;
                app.mine.pos_y = app.mine.pos_y + dy;
                app.mine.updateDisplay;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            colormap(app.UIAxes, 'white')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sabrinasapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end